classdef display_emg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        StartButton  matlab.ui.control.StateButton
    end


    properties (Access = private)
    end

    properties (Access = public)
        params          % parameters of the emg data (sampFreq, pre & postTriggerTime)
        emg_data_mmap   % memory map to emg data from emg_activity_mvc_goal.mlapp
        rc_data_mmap    % memory map of data for recruitment_curve.mlapp
        h_emg_line      % handle to emg data line
        h_t_min_line    % handle to vertical line at lower time for finding MEP
        h_t_max_line    % handle to vertical line at upper time for finding MEP
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % read in parameters   
            parameter_file = 'parameters.txt';
           if ~exist(parameter_file, 'file')
              [filename, pathname] = uigetfile( ...
                 {'*.txt';'*.*'}, ...
                 'Choose Parameter File');
              parameter_file = fullfile(pathname, filename);
           end
           if ~exist(parameter_file, 'file')
              error( 'error finding parameter file, %s', parameter_file)
           end
           % read in the parameter file
           keywords = {'addr' 'freq' 'period' 'chan' 'goal' 'pre' 'post'};
           defaults = {'192.168.1.102', 2500, 0.5, 1, 0.2, 50, 100};
           paramscell = readparamfile(parameter_file, keywords, defaults);
%            app.params.ipAddr    = paramscell{1};
           app.params.sampFreq  = paramscell{2};
%            app.params.avgPeriod = paramscell{3};
%            app.params.dispChan  = paramscell{4};
%            app.params.goalPct   = paramscell{5};
           app.params.preTriggerTime  = paramscell{6};
           app.params.postTriggerTime = paramscell{7};

           % 
           seg_time = (app.params.postTriggerTime + app.params.preTriggerTime) / 1000;
           seg_num_points = round(app.params.sampFreq*seg_time);
           % time vector in msec
           t = (0:1/app.params.sampFreq:(seg_time-1/app.params.sampFreq))*1000 - app.params.preTriggerTime;
 
            % emg data mem map file
           filename = 'emg_data.data';
           if ~exist(filename, 'file')
             error('MATLAB:display_emg:cannotOpenFile', ...
                      'Cannot open file "%s": %s.\nemg_activity_mvc_goal should be running before starting this app.', ...
                      filename, msg);
           end
           % Memory map the file.
           app.emg_data_mmap = memmapfile(filename, 'Writable', true, ...
               'Format', {'uint8', [1 1], 'new_data'; 'uint8', [1 1], 'magstim_val';
                'double', [1 seg_num_points], 'emg_data'});
            
           % rc data memory map file
           rc_filename = 'rc_data.data';
           if ~exist(rc_filename, 'file')
             [f, msg] = fopen(rc_filename, 'wb');
             if f ~= -1
                fwrite(f, zeros(1,1), 'uint8');
                fwrite(f, zeros(1,1), 'uint8');
                fwrite(f, zeros(1,1), 'double');
                fclose(f);
             else
             error('MATLAB:display_emg:cannotOpenFile', ...
                      'Cannot open file "%s": %s.', ...
                      rc_filename, msg);
             end
           end
           app.rc_data_mmap = memmapfile(rc_filename, 'Writable', true, ...
               'Format', {'uint8', [1 1], 'new_data'; 
                          'uint8', [1 1], 'magstim_val'; 
                          'double', [1 1], 'mep_val'});
           
          % init the axes
          app.h_emg_line = line(app.UIAxes, t, zeros(1, seg_num_points), ...
              'LineWidth', 3) ;
          line(app.UIAxes, app.UIAxes.XLim, [0 0]);
          line(app.UIAxes, [0 0], [-1e6 1e6]);
          app.h_t_min_line = line(app.UIAxes, [10 10], [-1e6 1e6], ...
              'LineWidth', 2, 'Color', [0 0.9 0]);
          app.h_t_max_line = line(app.UIAxes, [70 70], [-1e6 1e6], ...
              'LineWidth', 2, 'Color', [0 0.9 0]);
        end

        % Value changed function: StartButton
        function start_stop(app, event)
            if app.StartButton.Value
                app.StartButton.Text = 'Stop';
                get_emg_data(app)
            else
                app.StartButton.Text = 'Start';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time (msec)')
            ylabel(app.UIAxes, 'EMG (µV)')
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @start_stop, true);
            app.StartButton.Text = 'Start';
        end
    end

    methods (Access = public)

        % Construct app
        function app = display_emg

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end