classdef emg_activity_mvc_goal_2016b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        msgText                  matlab.ui.control.Label
        YmaxEditFieldLabel       matlab.ui.control.Label
        YmaxEditField            matlab.ui.control.NumericEditField
        ShowGoalCheckBox         matlab.ui.control.CheckBox
        MarkMVCCheckBox          matlab.ui.control.CheckBox
        ResetButton              matlab.ui.control.Button
        MVCEditField             matlab.ui.control.NumericEditField
        EMGChannelDropDownLabel  matlab.ui.control.Label
        EMGChannelDropDown       matlab.ui.control.DropDown
        StartButton              matlab.ui.control.Button
    end


    properties (Access = private)
        
    end

    properties (Access = public)
        tcp_port % Description
        params % parameters for tcpip connection to brainvision
        hLine % handle to emg data vertical line
        hStarMark % asterix text marker when there is a stim pulse
        monitorFlg % true = monitoring emg and showing goal
        chanInfo % emg channel info
        dataVec % vector to hold the incoming emg data
        hpFilt % parameters for high pass filter
        peakTracker % tracking the peak
        peakValVec % vector of values to compute peak
        peakVal % the compute peak value
        hPeakLine % handle to horizontal peak line
        quitFlg % stop reading in data from tcp port
    end

    methods (Access = private)


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [1 100 200 1100];
            app.UIAxes.Position = [20 133 155 900];
            app.UIAxes.XLim = [0.25 1.75];
            app.UIAxes.YLim = [0 300];
            app.UIAxes.XTick = [];
            app.hLine = line(app.UIAxes, [1 1], [0 1], 'LineWidth', 50);
            app.hStarMark = text(app.UIAxes, -0.3, 0, '*');
            app.monitorFlg = false;
            app.chanInfo = struct('resolution',[], 'names',{});
            
            app.peakTracker = true;
            app.peakValVec = zeros(1,100);
            app.peakVal = 0;
            app.hPeakLine = line(app.UIAxes, [0.5 1.5], [app.peakVal, app.peakVal], ...
                'LineWidth', 20, ...
                'Color', [209 36 36]/255);
            app.quitFlg = true;
        end

        % Button pushed function: StartButton
        function get_settings(app, event)
            if app.quitFlg
                parameter_file = 'parameters.txt';
                if ~exist(parameter_file, 'file')
                    [filename, pathname] = uigetfile( ...
                       {'*.txt';'*.*'}, ...
                        'Choose Parameter File');
                    parameter_file = fullfile(pathname, filename);
                end
                if ~exist(parameter_file, 'file')
                    error( 'error finding parameter file, %s', parameter_file)
                end
                % read in the parameter file
                	    keywords = {'addr' 'freq' 'period' 'chan' 'goal'};
                defaults = {'192.168.1.102', 2500, 0.5, 1, 0.2};
                	    paramscell = readparamfile(parameter_file, keywords, defaults);
                 app.params.ipAddr = paramscell{1};
                 app.params.sampFreq = paramscell{2};
                 app.params.avgPeriod = paramscell{3};
                 app.params.dispChan = paramscell{4};
                 app.params.goalPct = paramscell{5};
                         
                % create tcpip object
                app.tcp_port = tcpip(app.params.ipAddr, 51234);	%% local machine & 16-bit port (32-bit port: 51244)
                
                % configure object -- InputBufferSize
                get(app.tcp_port, 'InputBufferSize');
                set(app.tcp_port, 'InputBufferSize', 2000);
                
                % configure object -- byteOrder
                set(app.tcp_port, 'ByteOrder', 'littleEndian');
                
                % connect
                % === DEBUG - comment out to run without communicating with Recorder
                fopen(app.tcp_port);
                % verify connection status
                if ~strcmp(get(app.tcp_port, 'Status'), 'open')
                	disp('tcpip socket not open')
                	return
                end
                % === end DEBUG
                 tcpipinfo=instrhwinfo('tcpip')

                app.dataVec = zeros(round(app.params.sampFreq*app.params.avgPeriod),1);	
                % parameters to high pass filter at 10 Hz
                [app.hpFilt.b, app.hpFilt.a] = butter(4, 10/(app.params.sampFreq/2), 'high');
                
                app.StartButton.Text = 'Stop';
                app.quitFlg = false;
                
                run_emg(app)
            else
                app.quitFlg = true;
                app.StartButton.Text = 'Start';
            end
            
        end

        % Value changed function: YmaxEditField
        function yMaxCallback(app, event)
            value = app.YmaxEditField.Value;
            app.UIAxes.YLim = [0 value];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.msgText = uilabel(app.UIFigure);
            app.msgText.Text = 'No message';
            app.YmaxEditFieldLabel = uilabel(app.UIFigure);
            app.YmaxEditFieldLabel.Text = 'Ymax =';
            app.YmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @yMaxCallback, true);
            app.YmaxEditField.Value = 300;
            app.ShowGoalCheckBox = uicheckbox(app.UIFigure);
            app.ShowGoalCheckBox.Text = 'Show Goal';
            app.MarkMVCCheckBox = uicheckbox(app.UIFigure);
            app.MarkMVCCheckBox.Text = 'Mark MVC';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            app.MVCEditField = uieditfield(app.UIFigure, 'numeric');
            app.EMGChannelDropDownLabel = uilabel(app.UIFigure);
            app.EMGChannelDropDownLabel.Text = 'EMG Channel:';
            app.EMGChannelDropDown = uidropdown(app.UIFigure);
            app.EMGChannelDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.EMGChannelDropDown.Value = '1';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @get_settings, true);
            app.StartButton.Text = 'Start';
        end
    end

    methods (Access = public)

        % Construct app
        function app = emg_activity_mvc_goal_2016b

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end