classdef emg_rc < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		EMGDisplayRCUIFigure           matlab.ui.Figure
		TabGroup                       matlab.ui.container.TabGroup
		TabControls                    matlab.ui.container.Tab
		CheckBox_channel4              matlab.ui.control.CheckBox
		CheckBox_channel3              matlab.ui.control.CheckBox
		CheckBox_channel2              matlab.ui.control.CheckBox
		CheckBox_channel1              matlab.ui.control.CheckBox
		CheckBoxAverageEmg             matlab.ui.control.CheckBox
		SaveFileName                   matlab.ui.control.Label
		CheckBoxSici                   matlab.ui.control.CheckBox
		SelectButton                   matlab.ui.control.Button
		SaveLocationEditField          matlab.ui.control.EditField
		SaveLocationEditFieldLabel     matlab.ui.control.Label
		CheckBoxSavedata               matlab.ui.control.CheckBox
		EditFieldFilenameprefix        matlab.ui.control.EditField
		FilenameprefixEditFieldLabel   matlab.ui.control.Label
		CheckBoxRecruitCurve           matlab.ui.control.CheckBox
		CheckBoxDisplayMEP             matlab.ui.control.CheckBox
		TabParameters                  matlab.ui.container.Tab
		EMGDisplayFiltersPanel         matlab.ui.container.Panel
		NotchEditField                 matlab.ui.control.NumericEditField
		HzEditFieldLabel_3             matlab.ui.control.Label
		LPEditField                    matlab.ui.control.NumericEditField
		HzEditFieldLabel_2             matlab.ui.control.Label
		HPEditField                    matlab.ui.control.NumericEditField
		HzEditFieldLabel               matlab.ui.control.Label
		NotchCheckBox                  matlab.ui.control.CheckBox
		LPFilterCheckBox               matlab.ui.control.CheckBox
		HPFilterCheckBox               matlab.ui.control.CheckBox
		PostTriggerLabel               matlab.ui.control.Label
		PreTriggerLabel                matlab.ui.control.Label
		DisplaySaveEMGLabel            matlab.ui.control.Label
		BrainVisionSettingsPanel       matlab.ui.container.Panel
		MoreInfoLabel                  matlab.ui.control.Label
		BVEventLabel                   matlab.ui.control.Label
		EMGSamplingFreqLabel           matlab.ui.control.Label
		ActivationGoalLabel            matlab.ui.control.Label
		ParameterFileLabel             matlab.ui.control.Label
		PreEMGValueButtonGroup         matlab.ui.container.ButtonGroup
		MaxPeaktoPeakButton            matlab.ui.control.RadioButton
		MeanRectifiedValueButton       matlab.ui.control.RadioButton
		VLabel                         matlab.ui.control.Label
		MEPThresholdEditField          matlab.ui.control.NumericEditField
		MEPThresholdEditFieldLabel     matlab.ui.control.Label
		EMGPeaktoPeakValueButtonGroup  matlab.ui.container.ButtonGroup
		SubtractPreEMGppButton         matlab.ui.control.RadioButton
		UseMEPAbsoluteValueButton      matlab.ui.control.RadioButton
		msLabel                        matlab.ui.control.Label
		preEmgMaxEditField             matlab.ui.control.NumericEditField
		PreEMGRangeLabel               matlab.ui.control.Label
		preEmgMinEditField             matlab.ui.control.NumericEditField
	end


	properties (Access = public)
		params           % parameters of the emg data (sampFreq, pre & postTriggerTime)

		emg_data_fig     % figure to display an emg epoch
		h_disp_emg_axes  % axes to display emg data epoch

		emg_data_mmap       % memory map to emg data from emg_activity_mvc_goal.mlapp
		data_channels_mmap  % memory map with list of all muscle channels sent from emg_activity_mvc_goal.mlapp

		h_emg_line       % handle to emg data line
		h_t_min_line     % handle to vertical line at lower time for finding MEP
		h_t_max_line     % handle to vertical line at upper time for finding MEP
		mep_value_text   % uicontrol to display mep amplitude
		pre_emg_text     % uicontrol to display pre-stim emg
		xmin_edit        % uicontrol to display axes xmin
		xmax_edit        % uicontrol to display axes xmax
		sample_num_text  % uicontrol to display the saved sample number
		active_sample_checkbox      % uicontrol to indicate if a emg data sample is to be used (for averaging)
		next_sample_pushbutton      % uicontrol to go to next sample
		previous_sample_pushbutton  % uicontrol to go to previous sample

		fname_sample_struct % struct to store number of samples in each file created
		% filename (without .txt) is a field of the struct, the
		% value is the number of samples in the file
		fullfilename        % full path & file where data is being written

		%rc_data_mmap    % memory map of data for recruitment_curve.mlapp
		rc_fig          % recruit curve figure
		rc_axes         % axes in the recruit curve fig
		rc_fit_ui       % struct with uicontrols for rc fit
		rc_fit_info     % struct with curve fit info

		sici_fig        % sici & icf figure
		sici_popmenu    % popup menu in sici figure listing the type of stim (test, sici, icf)
		sici_axes       % axes on sici figure
		sici_ui         % struct with uicontrols for sici
		sici_info       % struct with sici info

		avg_emg_fig     % average emg data figure
		avg_emg_axes    % axes in the avg emg fig
		avg_num_lines   % uicontrol with number of lines being averaged

		% filter parameters
		display_filter
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app, varargin)
			request_param_fname = true;
			if nargin > 1
				param_fname = varargin{1};
				if exist(param_fname, 'file')
					param_fname = which(param_fname);
					request_param_fname = false;
				end
			end
			if request_param_fname % request parameter file
				disp('Select parameter file')
				[fname, pname] = uigetfile('*.txt', 'Select parameter file');
				param_fname = fullfile(pname, fname);
			end

			app.ParameterFileLabel.Text = ['Parameter File: ' param_fname];
			get_emg_rc_parameters(app, param_fname)
			setup_memmap(app, 'emg_data')
			setup_memmap(app, 'data_channels');
			app.EditFieldFilenameprefix.Value = ['x' datestr(now,'yyyymmdd_')];
			% save the channel number in the CheckBox_channelX userdata
			app.CheckBox_channel1.UserData = 1;
			app.CheckBox_channel2.UserData = 2;
			app.CheckBox_channel3.UserData = 3;
			app.CheckBox_channel4.UserData = 4;

			% compute the display filter parameters
			compute_display_filter(app);
		end

		% Value changed function: CheckBoxDisplayMEP
		function disp_mep_checkbox(app, event)
			if app.CheckBoxDisplayMEP.Value
				display_mep(app, 'start')
			else
				display_mep(app, 'stop')
			end
		end

		% Value changed function: CheckBoxRecruitCurve
		function rc_checkbox(app, event)
			if app.CheckBoxRecruitCurve.Value
				display_rc(app, 'start')
			else
				display_rc(app, 'stop')
			end
		end

		% Callback function
		function magspy_checkbox(app, event)
			if app.CheckBoxMagSpy.Value
				%                 [status, result] = system( 'matlab -r magspy &');
			else
			end

		end

		% Button pushed function: SelectButton
		function SelectButtonPushed(app, event)
			save_loc = uigetdir('/data/tms_recruitment_curve','Pick save location');
			app.SaveLocationEditField.Value = save_loc;
		end

		% Value changed function: CheckBoxSici
		function sici_checkbox(app, event)
			if app.CheckBoxSici.Value
				display_sici(app, 'start')
			else
				display_sici(app, 'stop')
			end
		end

		% Value changed function: EditFieldFilenameprefix
		function EditFieldFilenameprefixValueChanged(app, event)
			value = app.EditFieldFilenameprefix.Value;
			% verify that the value begins with a letter so valid struct
			% fields are created in app.fname_sample_struct
			if ~isempty(value) && ~isletter(value(1))
				app.EditFieldFilenameprefix.Value = ['x' app.EditFieldFilenameprefix.Value];
			end
		end

		% Value changed function: CheckBoxAverageEmg
		function CheckBoxAverageEmgValueChanged(app, event)
			if app.CheckBoxAverageEmg.Value
				display_average_emg(app, 'start')
			else
				display_average_emg(app, 'stop')
			end
		end

		% Value changed function: CheckBox_channel1, CheckBox_channel2, 
		% ...and 2 other components
		function CheckBox_channelX_ValueChanged(app, event)
			channel_number = event.Source.UserData;
			app.data_channels_mmap.Data(channel_number).save = uint8(event.Value);
		end

		% Value changed function: CheckBoxSavedata
		function CheckBoxSavedataValueChanged(app, event)
			if app.CheckBoxSavedata.Value  % saving data
				%  find the live_display channel and check its checkbox
				ch_struc = app.data_channels_mmap.Data;
				for c_cnt = 1:ch_struc(1).num_channels
					if ch_struc(c_cnt).live_display
						chkbox_var = ['CheckBox_channel' num2str(c_cnt)];
						app.(chkbox_var).Value = 1;
					end
				end
			else
				% uncheck all channels
				for c_cnt = 1:4
					chkbox_var = ['CheckBox_channel' num2str(c_cnt)];
					app.(chkbox_var).Value = 0;
				end
			end

		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.EMGDisplayRCUIFigure = uifigure('Visible', 'off');
			app.EMGDisplayRCUIFigure.Name = 'EMG Display & RC';
			app.TabGroup = uitabgroup(app.EMGDisplayRCUIFigure);
			app.TabControls = uitab(app.TabGroup);
			app.TabControls.Title = 'Controls';
			app.CheckBoxDisplayMEP = uicheckbox(app.TabControls);
			app.CheckBoxDisplayMEP.ValueChangedFcn = createCallbackFcn(app, @disp_mep_checkbox, true);
			app.CheckBoxDisplayMEP.Text = 'Display MEP';
			app.CheckBoxRecruitCurve = uicheckbox(app.TabControls);
			app.CheckBoxRecruitCurve.ValueChangedFcn = createCallbackFcn(app, @rc_checkbox, true);
			app.CheckBoxRecruitCurve.Text = 'Recruitment Curve';
			app.FilenameprefixEditFieldLabel = uilabel(app.TabControls);
			app.FilenameprefixEditFieldLabel.Text = 'File name prefix';
			app.EditFieldFilenameprefix = uieditfield(app.TabControls, 'text');
			app.EditFieldFilenameprefix.ValueChangedFcn = createCallbackFcn(app, @EditFieldFilenameprefixValueChanged, true);
			app.CheckBoxSavedata = uicheckbox(app.TabControls);
			app.CheckBoxSavedata.ValueChangedFcn = createCallbackFcn(app, @CheckBoxSavedataValueChanged, true);
			app.CheckBoxSavedata.Text = 'Save data';
			app.SaveLocationEditFieldLabel = uilabel(app.TabControls);
			app.SaveLocationEditFieldLabel.Text = 'Save Location';
			app.SaveLocationEditField = uieditfield(app.TabControls, 'text');
			app.SelectButton = uibutton(app.TabControls, 'push');
			app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
			app.SelectButton.Text = 'Select';
			app.CheckBoxSici = uicheckbox(app.TabControls);
			app.CheckBoxSici.ValueChangedFcn = createCallbackFcn(app, @sici_checkbox, true);
			app.CheckBoxSici.Text = 'SICI & ICF';
			app.SaveFileName = uilabel(app.TabControls);
			app.SaveFileName.Text = 'no file';
			app.CheckBoxAverageEmg = uicheckbox(app.TabControls);
			app.CheckBoxAverageEmg.ValueChangedFcn = createCallbackFcn(app, @CheckBoxAverageEmgValueChanged, true);
			app.CheckBoxAverageEmg.Text = 'Average EMG';
			app.CheckBox_channel1 = uicheckbox(app.TabControls);
			app.CheckBox_channel1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_channelX_ValueChanged, true);
			app.CheckBox_channel2 = uicheckbox(app.TabControls);
			app.CheckBox_channel2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_channelX_ValueChanged, true);
			app.CheckBox_channel2.Visible = 'off';
			app.CheckBox_channel3 = uicheckbox(app.TabControls);
			app.CheckBox_channel3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_channelX_ValueChanged, true);
			app.CheckBox_channel3.Visible = 'off';
			app.CheckBox_channel4 = uicheckbox(app.TabControls);
			app.CheckBox_channel4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_channelX_ValueChanged, true);
			app.CheckBox_channel4.Visible = 'off';
			app.TabParameters = uitab(app.TabGroup);
			app.TabParameters.Title = 'Parameters';
			app.preEmgMinEditField = uieditfield(app.TabParameters, 'numeric');
			app.preEmgMinEditField.Value = -101;
			app.PreEMGRangeLabel = uilabel(app.TabParameters);
			app.PreEMGRangeLabel.Text = 'Pre EMG Range';
			app.preEmgMaxEditField = uieditfield(app.TabParameters, 'numeric');
			app.preEmgMaxEditField.Value = -1;
			app.msLabel = uilabel(app.TabParameters);
			app.msLabel.Text = 'ms';
			app.EMGPeaktoPeakValueButtonGroup = uibuttongroup(app.TabParameters);
			app.EMGPeaktoPeakValueButtonGroup.Title = 'EMG Peak-to-Peak Value';
			app.UseMEPAbsoluteValueButton = uiradiobutton(app.EMGPeaktoPeakValueButtonGroup);
			app.UseMEPAbsoluteValueButton.Text = 'Use MEP Absolute Value';
			app.UseMEPAbsoluteValueButton.Value = true;
			app.SubtractPreEMGppButton = uiradiobutton(app.EMGPeaktoPeakValueButtonGroup);
			app.SubtractPreEMGppButton.Text = 'Subtract Pre EMG p-p';
			app.MEPThresholdEditFieldLabel = uilabel(app.TabParameters);
			app.MEPThresholdEditFieldLabel.Text = 'MEP Threshold';
			app.MEPThresholdEditField = uieditfield(app.TabParameters, 'numeric');
			app.MEPThresholdEditField.Value = 200;
			app.VLabel = uilabel(app.TabParameters);
			app.VLabel.Text = 'µV';
			app.PreEMGValueButtonGroup = uibuttongroup(app.TabParameters);
			app.PreEMGValueButtonGroup.Title = 'Pre EMG Value';
			app.MeanRectifiedValueButton = uiradiobutton(app.PreEMGValueButtonGroup);
			app.MeanRectifiedValueButton.Text = 'Mean Rectified Value';
			app.MeanRectifiedValueButton.Value = true;
			app.MaxPeaktoPeakButton = uiradiobutton(app.PreEMGValueButtonGroup);
			app.MaxPeaktoPeakButton.Text = 'Max Peak-to-Peak';
			app.ParameterFileLabel = uilabel(app.TabParameters);
			app.ParameterFileLabel.Text = 'Parameter File:';
			app.BrainVisionSettingsPanel = uipanel(app.TabParameters);
			app.BrainVisionSettingsPanel.Title = 'BrainVision Settings';
			app.ActivationGoalLabel = uilabel(app.BrainVisionSettingsPanel);
			app.ActivationGoalLabel.Text = 'Activation Goal: xx% mvc';
			app.EMGSamplingFreqLabel = uilabel(app.BrainVisionSettingsPanel);
			app.EMGSamplingFreqLabel.Text = 'Sampling Freq: xxx Hz';
			app.BVEventLabel = uilabel(app.BrainVisionSettingsPanel);
			app.BVEventLabel.Text = 'BrainVision Event Label: ';
			app.MoreInfoLabel = uilabel(app.BrainVisionSettingsPanel);
			app.MoreInfoLabel.Tooltip = {'These parameters are not editable here because they must be the same parameters used by emg_activity_mvc_goal.mlapp. To change them edit the ''parameters.txt'' file. Then restart both apps: emg_activity_mvc_goal.mlapp and emg_rc.mlapp with the new parameters.txt file.'};
			app.MoreInfoLabel.Text = 'Hover here for more info';
			app.DisplaySaveEMGLabel = uilabel(app.TabParameters);
			app.DisplaySaveEMGLabel.Text = 'Display & Save EMG:';
			app.PreTriggerLabel = uilabel(app.TabParameters);
			app.PreTriggerLabel.Text = 'Pre Trigger: xxx ms';
			app.PostTriggerLabel = uilabel(app.TabParameters);
			app.PostTriggerLabel.Text = 'Post Trigger: xxx ms';
			app.EMGDisplayFiltersPanel = uipanel(app.TabParameters);
			app.EMGDisplayFiltersPanel.Title = 'EMG Display Filters';
			app.HPFilterCheckBox = uicheckbox(app.EMGDisplayFiltersPanel);
			app.HPFilterCheckBox.Text = 'HP Filter';
			app.HPFilterCheckBox.Value = true;
			app.LPFilterCheckBox = uicheckbox(app.EMGDisplayFiltersPanel);
			app.LPFilterCheckBox.Text = 'LP Filter';
			app.NotchCheckBox = uicheckbox(app.EMGDisplayFiltersPanel);
			app.NotchCheckBox.Text = 'Notch';
			app.HzEditFieldLabel = uilabel(app.EMGDisplayFiltersPanel);
			app.HzEditFieldLabel.Text = 'Hz';
			app.HPEditField = uieditfield(app.EMGDisplayFiltersPanel, 'numeric');
			app.HPEditField.Value = 10;
			app.HzEditFieldLabel_2 = uilabel(app.EMGDisplayFiltersPanel);
			app.HzEditFieldLabel_2.Text = 'Hz';
			app.LPEditField = uieditfield(app.EMGDisplayFiltersPanel, 'numeric');
			app.LPEditField.Value = 500;
			app.HzEditFieldLabel_3 = uilabel(app.EMGDisplayFiltersPanel);
			app.HzEditFieldLabel_3.Text = 'Hz';
			app.NotchEditField = uieditfield(app.EMGDisplayFiltersPanel, 'numeric');
			app.NotchEditField.Value = 60;
			% Show the figure after all components are created
			app.EMGDisplayRCUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = emg_rc(varargin)

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.EMGDisplayRCUIFigure)

			% Execute the startup function
			runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.EMGDisplayRCUIFigure)
		end
	end
end