classdef emg_trace_viewer < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		EMG_Trace_Viewer_Fig       matlab.ui.Figure
		GridLayout                 matlab.ui.container.GridLayout
		LeftPanel                  matlab.ui.container.Panel
		TMSAnalysisHierarchyLabel  matlab.ui.control.Label
		SubjectTree                matlab.ui.container.Tree
		RightPanel                 matlab.ui.container.Panel
		Image                      matlab.ui.control.Image
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end


	properties (Access = public)
		expected_sessions        % list of the expected session names (different for tdcs vs myopro)
		expected_analysis_files  % list of the expected analysis file names (muscles) (different for tdcs vs myopro)
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			% look for analysis info files
			analysis_dir = pwd;
			if ~contains(analysis_dir, [filesep 'tms'], "IgnoreCase", true) || ...
					~contains(analysis_dir, [filesep 'analysis' filesep], "IgnoreCase", true)
				uialert(app.EMG_Trace_Viewer_Fig, {'Current folder is not within an Analysis hierchy.' ...
					'In the dialog box, choose an Analysis/TMS folder.'}, 'Analysis Folder', 'Icon', 'warning')
				analysis_dir = uigetdir(pwd, 'Select TMS analysis folder');
				if analysis_dir==0
					uialert(app.EMG_Trace_Viewer_Fig, 'No folder was selected.', 'No selection', 'Icon', 'warning')
					return
				end
			end
			% list of all emg trace files
			file_list = regexpdir(analysis_dir,'(.*epoch.*\.png)$');
			if length(file_list) < 1
				msg_txt = {'Found no *epoch*.png files.' ...
					'Select the TMS analysis folder with subject codes as folders.' ...
					'In the dialog box, choose an Analysis/TMS folder.'};
				uialert(app.EMG_Trace_Viewer_Fig, msg_txt, 'Files not found', 'Icon', 'warning')

				analysis_dir = uigetdir(pwd, 'Select TMS analysis folder');
				if analysis_dir==0
					uialert(app.EMG_Trace_Viewer_Fig, 'No folder was selected.', 'No selection', 'Icon', 'warning')
					return
				end
				file_list = regexpdir(analysis_dir,'(.*epoch.*\.png)$');
				if length(file_list) < 1
					msg_txt = {'Found no *epoch*.png files.' };
					uialert(app.EMG_Trace_Viewer_Fig, msg_txt, 'Files not found', 'Icon', 'warning')
					return
				end
			end

			% determine if this is gait or myomo
			if contains(lower(analysis_dir), 'tdcs')
				app.expected_sessions = {'pre' 'mid' 'post' 'followup'};
				app.expected_analysis_files = {'uninv_gastroc_rc' 'inv_gastroc_rc' 'uninv_ta_rc' 'inv_ta_rc' 'uninv_ta_sici' 'inv_ta_sici'};
			elseif contains(lower(analysis_dir), 'myopro')
				app.expected_sessions = {'week01' 'week04' 'week09' 'week18' 'week24'};
				app.expected_analysis_files = {'uninv_biceps_rc' 'inv_biceps_rc' 'uninv_triceps_rc' 'inv_triceps_rc' 'uninv_biceps_sici' 'inv_biceps_sici'};
			else
				error('do not know what sessions and muscles to use from this path: %s', analysis_dir)
			end

			% 			original_file_list = file_list; % save to reuse the list later
			%
			% 			% parse through the file list for subject, session, muscle -
			% 			% remove files from the list as they are added to the SubjectTree
			% 			while ~isempty(file_list)
			% 				% parse the filename
			% 				[pathname, ~, ~] = fileparts(file_list{1});
			%
			% 				% get the subject name format: [s|c][####][xxxx]
			% 				subj_str = regexp(pathname, '([sc][\d]+\w+)', 'match');
			% 				subj_str = subj_str{1};
			% 				uitreenode(app.SubjectTree, 'Text', subj_str);
			%
			% 				% all this subject's files
			% 				f_ind = contains(file_list, subj_str);
			%
			% 				% remove this subject files from the list
			% 				file_list(f_ind) = [];
			% 			end

			% for file, parse the filename for subject, session, muscle and add to tree
			for f_cnt = 1:length(file_list)
				% parse the filename
				[pathname, filename, ~] = fileparts(file_list{f_cnt});
				% get the subject name format: [s|c][####][xxxx]
				subj_str = regexp(pathname, '([sc][\d]+\w+)', 'match');
				subj_str = subj_str{1};
				% muscle format: [inv|uninv]_[gastroc|ta|biceps|triceps]_[rc|sici]
% 				if contains(filename, 'sici')
% 					keyboard
% 				end
				muscle_str = regexp(filename, '(inv|uninv)_(gastroc|ta|biceps|triceps)(.*)_(rc|sici)_', 'match');
				muscle_str = muscle_str{1};
				muscle_str = muscle_str(1:end-1); % remove trailing underscore
				% session
				session_str = regexp(pathname, '(pre\d*|mid\d*|post|followup|(week\d+))', 'match');
				session_str = session_str{1};
				% epoch
				epoch_str = regexp(filename, '_(epoch|epochs)_.+', 'match');
				epoch_str = strrep(epoch_str{1}, '_ep', 'ep');

				% is this subject in the tree?
				subj_node = find_node(app.SubjectTree, subj_str);
				if isempty(subj_node)
					% no, add the subject, muscle, session, epoch
					subj_node = uitreenode(app.SubjectTree, 'Text', subj_str);
					muscle_node = uitreenode(subj_node, 'Text', muscle_str);
					session_node = uitreenode(muscle_node, 'Text', session_str);
					uitreenode(session_node, 'Text', epoch_str, ...
						'NodeData', file_list{f_cnt});
				else
					% yes, is this muscle in the tree?
					muscle_node = find_node(subj_node, muscle_str);
					if isempty(muscle_node)
						% no, add the muscle, session, epoch
						muscle_node = uitreenode(subj_node, 'Text', muscle_str);
						session_node = uitreenode(muscle_node, 'Text', session_str);
						uitreenode(session_node, 'Text', epoch_str, ...
							'NodeData', file_list{f_cnt});
					else
						% yes, is this session in the tree?
						session_node = find_node(muscle_node, session_str);
						if isempty(session_node)
							% no, add the session, epoch
							session_node = uitreenode(muscle_node, 'Text', session_str);
							uitreenode(session_node, 'Text', epoch_str, ...
								'NodeData', file_list{f_cnt});
						else
							% yes, is this epoch in the tree
							epoch_node = find_node(session_node, epoch_str);
							if isempty(epoch_node)
								% no, add the epoch
								uitreenode(session_node, 'Text', epoch_str, ...
									'NodeData', file_list{f_cnt});
							else
								% yes, warn about a duplicate
								uialert(app.EMG_Trace_Viewer_Fig, 'Detected duplicate epoch', '', 'Icon', 'warning')
							end % epoch
						end % session
					end % muscle
				end % subject
			end % each file in file_list

			% expand all nodes
			expand(app.SubjectTree, 'all')
		end

		% Selection changed function: SubjectTree
		function SubjectTreeSelectionChanged(app, event)
			selectedNodes = app.SubjectTree.SelectedNodes;
			file_list = {};
			for n_cnt = 1:length(selectedNodes)
				% is it an epoch node?
				if contains(selectedNodes(n_cnt).Text, 'epoch')
					file_list = [file_list; selectedNodes(n_cnt).NodeData];
				end
			end
			% update the images in the right panel
			if ~isempty(file_list)
				%update_emg_traces(app.RightPanel, file_list)
				%im = uiimage(app.RightPanel, 'Position', [10 10 400 400]);
				app.Image.ImageSource = file_list{1};
			end
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.EMG_Trace_Viewer_Fig.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {423, 423};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {220, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.EMG_Trace_Viewer_Fig = uifigure('Visible', 'off');
			app.EMG_Trace_Viewer_Fig.Name = 'EMG Trace Viewer';
			app.EMG_Trace_Viewer_Fig.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.GridLayout = uigridlayout(app.EMG_Trace_Viewer_Fig);
			app.LeftPanel = uipanel(app.GridLayout);
			app.SubjectTree = uitree(app.LeftPanel);
			app.SubjectTree.SelectionChangedFcn = createCallbackFcn(app, @SubjectTreeSelectionChanged, true);
			app.TMSAnalysisHierarchyLabel = uilabel(app.LeftPanel);
			app.TMSAnalysisHierarchyLabel.Text = 'TMS Analysis Hierarchy';
			app.RightPanel = uipanel(app.GridLayout);
			app.Image = uiimage(app.RightPanel);
			% Show the figure after all components are created
			app.EMG_Trace_Viewer_Fig.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = emg_trace_viewer

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.EMG_Trace_Viewer_Fig)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.EMG_Trace_Viewer_Fig)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.EMG_Trace_Viewer_Fig)
		end
	end
end