classdef info_to_db < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		TMSInfotoDatabaseUIFigure      matlab.ui.Figure
		GridLayout                     matlab.ui.container.GridLayout
		LeftPanel                      matlab.ui.container.Panel
		TMSAnalysisFolderHierarchyLabel  matlab.ui.control.Label
		SubjectTree                    matlab.ui.container.Tree
		RightPanel                     matlab.ui.container.Panel
		SiciIcfInfoPanel               matlab.ui.container.Panel
		UITable_Unique_Stims_sici      matlab.ui.control.Table
		VerifydateEditField_sici       matlab.ui.control.EditField
		VerifydateEditField_2Label     matlab.ui.control.Label
		AnalysisdateEditField_sici     matlab.ui.control.EditField
		AnalysisdateEditField_2Label   matlab.ui.control.Label
		dbLastupdatedEditField_sici    matlab.ui.control.EditField
		CommentsEditField_sici         matlab.ui.control.EditField
		CommentsEditFieldLabel_sici    matlab.ui.control.Label
		VerifiedbyEditField_sici       matlab.ui.control.EditField
		VerifiedbyEditFieldLabel_sici  matlab.ui.control.Label
		AnalyzedbyEditField_sici       matlab.ui.control.EditField
		AnalyzedbyEditFieldLabel_sici  matlab.ui.control.Label
		SendtoDatabaseButton_sici      matlab.ui.control.Button
		DatabaseinfomatchesCheckBox_sici  matlab.ui.control.CheckBox
		InDatabaseCheckBox_sici        matlab.ui.control.CheckBox
		Image_sici                     matlab.ui.control.Image
		NumSDEditField_sici            matlab.ui.control.NumericEditField
		NumSDEditField_2Label          matlab.ui.control.Label
		MEPMaxInfoPanel                matlab.ui.container.Panel
		RCInfoLabel                    matlab.ui.control.Label
		dbLastupdatedEditField_2       matlab.ui.control.EditField
		DatabaseinfomatchesCheckBox_2  matlab.ui.control.CheckBox
		InDatabaseCheckBox_2           matlab.ui.control.CheckBox
		MEPMaxLatencyLabel             matlab.ui.control.Label
		UITable_Unique_Stims           matlab.ui.control.Table
		dbLastupdatedEditField         matlab.ui.control.EditField
		MEPmaxatMaxSOCheckBox          matlab.ui.control.CheckBox
		CommentsEditField              matlab.ui.control.EditField
		CommentsEditFieldLabel         matlab.ui.control.Label
		VerifiedbyEditField            matlab.ui.control.EditField
		VerifiedbyEditFieldLabel       matlab.ui.control.Label
		AnalyzedbyEditField            matlab.ui.control.EditField
		AnalyzedbyEditFieldLabel       matlab.ui.control.Label
		SendtoDatabaseButton           matlab.ui.control.Button
		DatabaseinfomatchesCheckBox    matlab.ui.control.CheckBox
		InDatabaseCheckBox             matlab.ui.control.CheckBox
		VerifydateEditField            matlab.ui.control.EditField
		VerifydateEditFieldLabel       matlab.ui.control.Label
		AnalysisdateEditField          matlab.ui.control.EditField
		AnalysisdateEditFieldLabel     matlab.ui.control.Label
		EStimMmaxEditField             matlab.ui.control.NumericEditField
		EStimMmaxEditFieldLabel        matlab.ui.control.Label
		RecruitmentCurvePlateauedCheckBox  matlab.ui.control.CheckBox
		NumSDEditField                 matlab.ui.control.NumericEditField
		NumSDEditFieldLabel            matlab.ui.control.Label
		Image_rc                       matlab.ui.control.Image
		MusclePanel                    matlab.ui.container.Panel
		SessionEditField_muscP         matlab.ui.control.EditField
		SubjectEditField_muscP         matlab.ui.control.EditField
		MuscleEditField_muscP          matlab.ui.control.EditField
		SessionPanel                   matlab.ui.container.Panel
		MissingFilesTextArea           matlab.ui.control.TextArea
		MissingFilesTextAreaLabel      matlab.ui.control.Label
		SubjectEditField_sessP         matlab.ui.control.EditField
		SessionEditField_sessP         matlab.ui.control.EditField
		SubjectPanel                   matlab.ui.container.Panel
		ExtraSessionsTextArea          matlab.ui.control.TextArea
		ExtraSessionsLabel             matlab.ui.control.Label
		MissingSessionsTextArea        matlab.ui.control.TextArea
		MissingSessionsTextAreaLabel   matlab.ui.control.Label
		SubjectEditField_subjP         matlab.ui.control.EditField
		InfoTblContextMenu             matlab.ui.container.ContextMenu
		ShowCommentsMenu               matlab.ui.container.Menu
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end


	properties (Access = public)
		db_str		             % which database
		expected_sessions        % names of data collection sesssions
		expected_analysis_files  % names of the analysis files
		datapoint_tbl			 % csv datapoint table read in
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			% look for analysis info files
			analysis_dir = pwd;
			if ~contains(analysis_dir, [filesep 'tms'], "IgnoreCase", true) || ...
					~contains(analysis_dir, [filesep 'analysis' filesep], "IgnoreCase", true)
				uialert(app.TMSInfotoDatabaseUIFigure, {'Current folder is not within an Analysis hierchy.' ...
					'In the dialog box, choose an Analysis/TMS folder.'}, 'Analysis Folder', 'Icon', 'warning')
				analysis_dir = uigetdir(pwd, 'Select TMS analysis folder');
				if analysis_dir==0
					uialert(app.TMSInfotoDatabaseUIFigure, 'No folder was selected.', 'No selection', 'Icon', 'warning')
					return
				end
			end
			% list of all files with info to go to the database
			file_list = regexpdir(analysis_dir,'(.*datapoints_analysis_info\.txt)$');
			if length(file_list) < 1
				msg_txt = {'Found no *datapoints_analysis_info.txt files.' ...
					'Select the TMS analysis folder with subject codes as folders.' ...
					'In the dialog box, choose an Analysis/TMS folder.'};
				uialert(app.TMSInfotoDatabaseUIFigure, msg_txt, 'Files not found', 'Icon', 'warning')

				analysis_dir = uigetdir(pwd, 'Select TMS analysis folder');
				if analysis_dir==0
					uialert(app.TMSInfotoDatabaseUIFigure, 'No folder was selected.', 'No selection', 'Icon', 'warning')
					return
				end
				file_list = regexpdir(analysis_dir,'(.*datapoints_analysis_info\.txt)$');
				if length(file_list) < 1
					msg_txt = {'Found no *datapoints_analysis_info.txt files.' };
					uialert(app.TMSInfotoDatabaseUIFigure, msg_txt, 'Files not found', 'Icon', 'warning')
					return
				end
			end

			% determine if this is gait or myomo
			if contains(lower(analysis_dir), 'tdcs')
				app.db_str = 'tdcs_vgait';
				app.expected_sessions = {'pre' 'mid' 'post' 'followup'};
				app.expected_analysis_files = {'uninv_gastroc_rc' 'inv_gastroc_rc' 'uninv_ta_rc' 'inv_ta_rc' 'uninv_ta_sici' 'inv_ta_sici'};
			elseif contains(lower(analysis_dir), 'myopro')
				app.db_str = 'myopro_merit';
				app.expected_sessions = {'week01' 'week04' 'week09' 'week18' 'week24'};
				app.expected_analysis_files = {'uninv_biceps_rc' 'inv_biceps_rc' 'uninv_triceps_rc' 'inv_triceps_rc' 'uninv_biceps_sici' 'inv_biceps_sici'};
			else
				error('do not know what database to use from this path: %s', analysis_dir)
			end

			% parse through the file list for subject, session, muscle -
			% remove files from the list as they are added to the Subject
			% Tree
			while ~isempty(file_list)
				% parse the filename
				[pathname, ~, ~] = fileparts(file_list{1});

				% get the subject name format: [s|c][####][xxxx]
				subj_str = regexp(pathname, '([sc][\d]+\w+)', 'match');
				subj_str = subj_str{1};
				uitreenode(app.SubjectTree, 'Text', subj_str);

				% all this subject's files
				f_ind = contains(file_list, subj_str);

				% remove this subject files from the list
				file_list(f_ind) = [];

			end

			% for each of the subjects in the tree, add their sessions as
			% children
			for s_cnt = 1:length(app.SubjectTree.Children)
				subj_str = app.SubjectTree.Children(s_cnt).Text;
				% in analysis_dir/subj_str, get files/folders
				f_list = dir([analysis_dir filesep subj_str]);
				for f_cnt = 1:length(f_list)
					f_name = f_list(f_cnt).name;
					% look for the session
					session_str = lower(regexp(f_name, '(pre\d*|mid\d*|post|followup\d*|(week\d+))', 'match'));
					if ~isempty(session_str)
						% add the session to the subject node
						tmp_node = uitreenode(app.SubjectTree.Children(s_cnt), 'Text', session_str{:});
						% look for *datapoints_analysis_info.txt files
						file_list = regexpdir([analysis_dir filesep subj_str filesep session_str{:}], ...
							'(.*datapoints_analysis_info\.txt)$');
						if ~isempty(file_list)
							% for each file, add to tree
							for n_cnt = 1:length(file_list)
								filename = file_list{n_cnt};
								% make sure 'do_not_use' is not in the path
								if ~contains(filename, 'do_not_use')
									% look for the muscle & rc or sici FIXME -
									% add provision for myopro muscles
									muscle_str = lower(regexp(filename, ...
										'(uninv_gastroc_rc|inv_gastroc_rc|uninv_ta_rc|inv_ta_rc|uninv_ta_sici|inv_ta_sici)', ...
										'match'));
									if ~isempty(muscle_str)
										uitreenode(tmp_node, 'Text', muscle_str{:}, ...
											'NodeData', filename);
									end
								end
							end % each file in a session
						end
					end % each session
				end
			end % each subject in the tree

		end

		% Selection changed function: SubjectTree
		function SubjectTreeSelectionChanged(app, event)
			selectedNode = app.SubjectTree.SelectedNodes;
			% only 1 node can be selected at a time

			% is the node a subject, session, or muscle/file?

			% test for subject node: name format: [s|c][####][xxxx]
			subj_str = regexp(selectedNode.Text, '([sc][\d]+\w+)', 'match');
			if ~isempty(subj_str)
				% it's a subject node
				app.SubjectPanel.Visible = 'on';
				app.SessionPanel.Visible = 'off';
				app.MusclePanel.Visible = 'off';
				app.MEPMaxInfoPanel.Visible = 'off';
				app.SiciIcfInfoPanel.Visible = 'off';
				update_subject_panel(subj_str{:}, app)
			else
				% test for session node:
				session_str = lower(regexp(selectedNode.Text, '(pre|mid|post|followup|(week\d+))', 'match'));
				if ~isempty(session_str)
					% it's a session node
					app.SubjectPanel.Visible = 'off';
					app.SessionPanel.Visible = 'on';
					app.MusclePanel.Visible = 'off';
					app.MEPMaxInfoPanel.Visible = 'off';
					app.SiciIcfInfoPanel.Visible = 'off';
					update_session_panel(selectedNode.Parent.Text, app)
				else
					% it's a muscle/file node:
					app.SubjectPanel.Visible = 'off';
					app.SessionPanel.Visible = 'off';
					app.MusclePanel.Visible = 'on';
					app.MEPMaxInfoPanel.Visible = 'off';
					update_muscle_or_sici_panel(selectedNode.Parent.Parent.Text, app)
				end
			end

		end

		% Button pushed function: SendtoDatabaseButton
		function SendtoDatabaseButtonPushed(app, event)
			send_mep_max_to_db(app)
			norm_success = send_rc_fitinfo_to_db(app, 'norm');
			if norm_success % get the update date
				norm_update = app.dbLastupdatedEditField_2.Value;
			end
			not_norm_success = send_rc_fitinfo_to_db(app, 'not_norm');
			if not_norm_success % get update date
				not_norm_update = app.dbLastupdatedEditField_2.Value;
			end
			% update the in database checkbox and database matches checkbox
			if norm_success && not_norm_success
				app.InDatabaseCheckBox_2.Value = true;
				app.DatabaseinfomatchesCheckBox_2.Value = true;
				% make the update date the later of the 2
				max_update_date = max([datetime(norm_update) datetime(not_norm_update)]);
				app.dbLastupdatedEditField_2.Value = string(max_update_date, 'yyyy-MM-dd HH:mm:ss');
			end
		end

		% Value changed function: RecruitmentCurvePlateauedCheckBox
		function RecruitmentCurvePlateauedCheckBoxValueChanged(app, event)
			% 			value = app.RecruitmentCurvePlateauedCheckBox.Value;
			% is the data in the database?
			is_mep_info_in_db(app)
		end

		% Menu selected function: ShowCommentsMenu
		function ShowCommentsMenuSelected(app, event)
			% 			disp('menu selected')
			% which panel is visible the rc or sici panel?
			if strcmp(app.MEPMaxInfoPanel.Visible, 'on')
				uitbl = app.UITable_Unique_Stims;
			elseif strcmp(app.SiciIcfInfoPanel.Visible, 'on')
				uitbl = app.UITable_Unique_Stims_sici;
			else
				return
			end
			if ~isempty(uitbl.Selection)
				selected_table_row = uitbl.Selection(1);
			else
				uialert(app.TMSInfotoDatabaseUIFigure, 'You need to select a row.', 'Show Comments', 'Icon', 'warning')
				return
			end
			% find rows in the datapoint table matching the stimulator
			% settings for this row
			magstim = uitbl.Data.MagStim_Setting(selected_table_row);
			bistim = uitbl.Data.BiStim_Setting(selected_table_row);
			isi = uitbl.Data.ISI_ms(selected_table_row);
			selected_rows = app.datapoint_tbl(app.datapoint_tbl.MagStim_Setting == magstim & ...
				app.datapoint_tbl.BiStim_Setting == bistim & ...
				app.datapoint_tbl.ISI_ms == isi , :);
			% display the comments
			if ~isnumeric(selected_rows.comments)
				for r_num = 1:height(selected_rows)
					msg_txt{r_num} = [num2str(r_num) ' - ' selected_rows.comments{r_num}]; %#ok<AGROW>
				end
				uialert(app.TMSInfotoDatabaseUIFigure, msg_txt, 'Comments', 'Icon', 'info');
			else
				uialert(app.TMSInfotoDatabaseUIFigure, 'No comments for this row.', 'Show Comments', 'Icon', 'warning')
			end


		end

		% Image clicked function: Image_sici
		function Image_siciClicked(app, event)
			cmd_str = sprintf('open "%s" ', app.Image_sici.ImageSource);
			system(cmd_str)
		end

		% Image clicked function: Image_rc
		function Image_rcClicked(app, event)
			cmd_str = sprintf('open "%s" ', app.Image_rc.ImageSource);
			system(cmd_str)
		end

		% Button pushed function: SendtoDatabaseButton_sici
		function SendtoDatabaseButton_siciPushed(app, event)
			send_sici_icf_to_db(app)
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.TMSInfotoDatabaseUIFigure.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {484, 484};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {244, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.TMSInfotoDatabaseUIFigure = uifigure('Visible', 'off');
			app.TMSInfotoDatabaseUIFigure.Name = 'TMS Info to Database';
			app.TMSInfotoDatabaseUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.GridLayout = uigridlayout(app.TMSInfotoDatabaseUIFigure);
			app.LeftPanel = uipanel(app.GridLayout);
			app.SubjectTree = uitree(app.LeftPanel);
			app.SubjectTree.SelectionChangedFcn = createCallbackFcn(app, @SubjectTreeSelectionChanged, true);
			app.TMSAnalysisFolderHierarchyLabel = uilabel(app.LeftPanel);
			app.TMSAnalysisFolderHierarchyLabel.Text = 'TMS Analysis Folder Hierarchy';
			app.RightPanel = uipanel(app.GridLayout);
			app.SubjectPanel = uipanel(app.RightPanel);
			app.SubjectPanel.Title = 'Subject';
			app.SubjectPanel.Visible = 'off';
			app.SubjectEditField_subjP = uieditfield(app.SubjectPanel, 'text');
			app.SubjectEditField_subjP.Editable = 'off';
			app.MissingSessionsTextAreaLabel = uilabel(app.SubjectPanel);
			app.MissingSessionsTextAreaLabel.Text = 'Missing Sessions';
			app.MissingSessionsTextArea = uitextarea(app.SubjectPanel);
			app.MissingSessionsTextArea.Editable = 'off';
			app.ExtraSessionsLabel = uilabel(app.SubjectPanel);
			app.ExtraSessionsLabel.Text = 'Extra Sessions';
			app.ExtraSessionsTextArea = uitextarea(app.SubjectPanel);
			app.ExtraSessionsTextArea.Editable = 'off';
			app.SessionPanel = uipanel(app.RightPanel);
			app.SessionPanel.Title = 'Session';
			app.SessionPanel.Visible = 'off';
			app.SessionEditField_sessP = uieditfield(app.SessionPanel, 'text');
			app.SessionEditField_sessP.Editable = 'off';
			app.SubjectEditField_sessP = uieditfield(app.SessionPanel, 'text');
			app.SubjectEditField_sessP.Editable = 'off';
			app.MissingFilesTextAreaLabel = uilabel(app.SessionPanel);
			app.MissingFilesTextAreaLabel.Text = 'Missing Files';
			app.MissingFilesTextArea = uitextarea(app.SessionPanel);
			app.MissingFilesTextArea.Editable = 'off';
			app.MusclePanel = uipanel(app.RightPanel);
			app.MusclePanel.Title = 'Muscle';
			app.MusclePanel.Visible = 'off';
			app.MuscleEditField_muscP = uieditfield(app.MusclePanel, 'text');
			app.MuscleEditField_muscP.Editable = 'off';
			app.SubjectEditField_muscP = uieditfield(app.MusclePanel, 'text');
			app.SubjectEditField_muscP.Editable = 'off';
			app.SessionEditField_muscP = uieditfield(app.MusclePanel, 'text');
			app.SessionEditField_muscP.Editable = 'off';
			app.MEPMaxInfoPanel = uipanel(app.RightPanel);
			app.MEPMaxInfoPanel.Title = 'MEP Max Info';
			app.MEPMaxInfoPanel.Visible = 'off';
			app.Image_rc = uiimage(app.MEPMaxInfoPanel);
			app.Image_rc.ImageClickedFcn = createCallbackFcn(app, @Image_rcClicked, true);
			app.NumSDEditFieldLabel = uilabel(app.MEPMaxInfoPanel);
			app.NumSDEditFieldLabel.Text = 'Num SD';
			app.NumSDEditField = uieditfield(app.MEPMaxInfoPanel, 'numeric');
			app.NumSDEditField.Editable = 'off';
			app.RecruitmentCurvePlateauedCheckBox = uicheckbox(app.MEPMaxInfoPanel);
			app.RecruitmentCurvePlateauedCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecruitmentCurvePlateauedCheckBoxValueChanged, true);
			app.RecruitmentCurvePlateauedCheckBox.Text = 'Recruitment Curve Plateaued';
			app.EStimMmaxEditFieldLabel = uilabel(app.MEPMaxInfoPanel);
			app.EStimMmaxEditFieldLabel.Text = 'E Stim M-max';
			app.EStimMmaxEditField = uieditfield(app.MEPMaxInfoPanel, 'numeric');
			app.EStimMmaxEditField.ValueDisplayFormat = '%.0f';
			app.EStimMmaxEditField.Editable = 'off';
			app.AnalysisdateEditFieldLabel = uilabel(app.MEPMaxInfoPanel);
			app.AnalysisdateEditFieldLabel.Text = 'Analysis date';
			app.AnalysisdateEditField = uieditfield(app.MEPMaxInfoPanel, 'text');
			app.AnalysisdateEditField.Editable = 'off';
			app.VerifydateEditFieldLabel = uilabel(app.MEPMaxInfoPanel);
			app.VerifydateEditFieldLabel.Text = 'Verify date';
			app.VerifydateEditField = uieditfield(app.MEPMaxInfoPanel, 'text');
			app.InDatabaseCheckBox = uicheckbox(app.MEPMaxInfoPanel);
			app.InDatabaseCheckBox.Text = 'In Database';
			app.DatabaseinfomatchesCheckBox = uicheckbox(app.MEPMaxInfoPanel);
			app.DatabaseinfomatchesCheckBox.Text = 'Database info matches';
			app.SendtoDatabaseButton = uibutton(app.MEPMaxInfoPanel, 'push');
			app.SendtoDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoDatabaseButtonPushed, true);
			app.SendtoDatabaseButton.Text = 'Send to Database';
			app.AnalyzedbyEditFieldLabel = uilabel(app.MEPMaxInfoPanel);
			app.AnalyzedbyEditFieldLabel.Text = 'Analyzed by';
			app.AnalyzedbyEditField = uieditfield(app.MEPMaxInfoPanel, 'text');
			app.AnalyzedbyEditField.Editable = 'off';
			app.VerifiedbyEditFieldLabel = uilabel(app.MEPMaxInfoPanel);
			app.VerifiedbyEditFieldLabel.Text = 'Verified by';
			app.VerifiedbyEditField = uieditfield(app.MEPMaxInfoPanel, 'text');
			app.CommentsEditFieldLabel = uilabel(app.MEPMaxInfoPanel);
			app.CommentsEditFieldLabel.Text = 'Comments';
			app.CommentsEditField = uieditfield(app.MEPMaxInfoPanel, 'text');
			app.MEPmaxatMaxSOCheckBox = uicheckbox(app.MEPMaxInfoPanel);
			app.MEPmaxatMaxSOCheckBox.Text = 'MEP max at Max SO';
			app.dbLastupdatedEditField = uieditfield(app.MEPMaxInfoPanel, 'text');
			app.dbLastupdatedEditField.Editable = 'off';
			app.UITable_Unique_Stims = uitable(app.MEPMaxInfoPanel);
			app.UITable_Unique_Stims.ColumnName = {'Magstim'; 'Bistim'; 'ISI'; 'ESO'; 'N'; 'Num MEPs'; 'Latency'; 'NLatManAdj'; 'End'; 'NEndManAdj'; 'Amplitude'; 'Num Comments'};
			app.UITable_Unique_Stims.RowName = {};
			app.MEPMaxLatencyLabel = uilabel(app.MEPMaxInfoPanel);
			app.MEPMaxLatencyLabel.Text = 'MEP Max & Latency';
			app.InDatabaseCheckBox_2 = uicheckbox(app.MEPMaxInfoPanel);
			app.InDatabaseCheckBox_2.Text = 'In Database';
			app.DatabaseinfomatchesCheckBox_2 = uicheckbox(app.MEPMaxInfoPanel);
			app.DatabaseinfomatchesCheckBox_2.Text = 'Database info matches';
			app.dbLastupdatedEditField_2 = uieditfield(app.MEPMaxInfoPanel, 'text');
			app.dbLastupdatedEditField_2.Editable = 'off';
			app.RCInfoLabel = uilabel(app.MEPMaxInfoPanel);
			app.RCInfoLabel.Text = 'RC Info';
			app.SiciIcfInfoPanel = uipanel(app.RightPanel);
			app.SiciIcfInfoPanel.Title = 'SICI/ICF Info';
			app.SiciIcfInfoPanel.Visible = 'off';
			app.NumSDEditField_2Label = uilabel(app.SiciIcfInfoPanel);
			app.NumSDEditField_2Label.Text = 'Num SD';
			app.NumSDEditField_sici = uieditfield(app.SiciIcfInfoPanel, 'numeric');
			app.NumSDEditField_sici.Editable = 'off';
			app.Image_sici = uiimage(app.SiciIcfInfoPanel);
			app.Image_sici.ImageClickedFcn = createCallbackFcn(app, @Image_siciClicked, true);
			app.InDatabaseCheckBox_sici = uicheckbox(app.SiciIcfInfoPanel);
			app.InDatabaseCheckBox_sici.Text = 'In Database';
			app.DatabaseinfomatchesCheckBox_sici = uicheckbox(app.SiciIcfInfoPanel);
			app.DatabaseinfomatchesCheckBox_sici.Text = 'Database info matches';
			app.SendtoDatabaseButton_sici = uibutton(app.SiciIcfInfoPanel, 'push');
			app.SendtoDatabaseButton_sici.ButtonPushedFcn = createCallbackFcn(app, @SendtoDatabaseButton_siciPushed, true);
			app.SendtoDatabaseButton_sici.Text = 'Send to Database';
			app.AnalyzedbyEditFieldLabel_sici = uilabel(app.SiciIcfInfoPanel);
			app.AnalyzedbyEditFieldLabel_sici.Text = 'Analyzed by';
			app.AnalyzedbyEditField_sici = uieditfield(app.SiciIcfInfoPanel, 'text');
			app.AnalyzedbyEditField_sici.Editable = 'off';
			app.VerifiedbyEditFieldLabel_sici = uilabel(app.SiciIcfInfoPanel);
			app.VerifiedbyEditFieldLabel_sici.Text = 'Verified by';
			app.VerifiedbyEditField_sici = uieditfield(app.SiciIcfInfoPanel, 'text');
			app.CommentsEditFieldLabel_sici = uilabel(app.SiciIcfInfoPanel);
			app.CommentsEditFieldLabel_sici.Text = 'Comments';
			app.CommentsEditField_sici = uieditfield(app.SiciIcfInfoPanel, 'text');
			app.dbLastupdatedEditField_sici = uieditfield(app.SiciIcfInfoPanel, 'text');
			app.dbLastupdatedEditField_sici.Editable = 'off';
			app.AnalysisdateEditField_2Label = uilabel(app.SiciIcfInfoPanel);
			app.AnalysisdateEditField_2Label.Text = 'Analysis date';
			app.AnalysisdateEditField_sici = uieditfield(app.SiciIcfInfoPanel, 'text');
			app.AnalysisdateEditField_sici.Editable = 'off';
			app.VerifydateEditField_2Label = uilabel(app.SiciIcfInfoPanel);
			app.VerifydateEditField_2Label.Text = 'Verify date';
			app.VerifydateEditField_sici = uieditfield(app.SiciIcfInfoPanel, 'text');
			app.UITable_Unique_Stims_sici = uitable(app.SiciIcfInfoPanel);
			app.UITable_Unique_Stims_sici.ColumnName = {'Magstim'; 'Bistim'; 'ISI'; 'Stim Type'; 'N'; 'Num MEPs'; 'Latency'; 'NLatManAdj'; 'End'; 'NEndManAdj'; 'Amplitude'; 'Num Comments'};
			app.UITable_Unique_Stims_sici.RowName = {};
			app.InfoTblContextMenu = uicontextmenu(app.TMSInfotoDatabaseUIFigure);
			app.ShowCommentsMenu = uimenu(app.InfoTblContextMenu);
			app.ShowCommentsMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowCommentsMenuSelected, true);
			app.ShowCommentsMenu.Text = 'Show Comments';
			% Assign app.InfoTblContextMenu
			app.UITable_Unique_Stims.ContextMenu = app.InfoTblContextMenu;
			app.UITable_Unique_Stims_sici.ContextMenu = app.InfoTblContextMenu;
			% Show the figure after all components are created
			app.TMSInfotoDatabaseUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = info_to_db

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.TMSInfotoDatabaseUIFigure)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.TMSInfotoDatabaseUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.TMSInfotoDatabaseUIFigure)
		end
	end
end