classdef magspy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        magstimUIFig           matlab.ui.Figure
        RunButton              matlab.ui.control.StateButton
        FindPortsButton        matlab.ui.control.Button
        ClearPortsButton       matlab.ui.control.Button
        Delay                  matlab.ui.control.NumericEditField
        magstim_power          matlab.ui.control.NumericEditField
        ports_menu             matlab.ui.control.DropDown
        CheckeveryLabel        matlab.ui.control.Label
        msecLabel              matlab.ui.control.Label
        LabelPercent1          matlab.ui.control.Label
        LabelPercent2          matlab.ui.control.Label
        magstim_power_2        matlab.ui.control.NumericEditField
        ISILabel               matlab.ui.control.Label
        magstim_isi            matlab.ui.control.NumericEditField
        msLabel                matlab.ui.control.Label
        MagStimLabel           matlab.ui.control.Label
        ConditioningStimLabel  matlab.ui.control.Label
        TestStimLabel          matlab.ui.control.Label
        MagstimOnlyChkBox      matlab.ui.control.CheckBox
    end

 
 properties (Access = public)
  sport %
  currportname %
  currportprefix %
 end
 
 methods (Access = public)
  
  function read_magstim(app)
   refreshdata(app.magstimUIFig)
   drawnow
   value=app.RunButton.Value;
   try
    fclose(app.sport);
   catch
   end
   try
        fopen(app.sport);
   catch
       disp('Serial port to Magstim not open. Try choosing another serial port.')
       return
   end
   app.RunButton.Text = 'Stop';
   if app.sport.BytesAvailable > 0
    fscanf(app.sport,'%s',app.sport.BytesAvailable); % discard previous contents
   end
   
   % open file for memmap and communicating
   filename = 'magstim_val.data';
   if ~exist(filename, 'file')
%    fid = fopen('magstim_val.txt', 'w');
     [f, msg] = fopen(filename, 'wb');
     if f ~= -1
        fwrite(f, zeros(1,3*8), 'uint8'); % 3 ints
        fclose(f);
     else
        error('MATLAB:demo:send:cannotOpenFile', ...
              'Cannot open file "%s": %s.', filename, msg);
     end
   end
   % Memory map the file.
   m = memmapfile(filename, 'Writable', true, 'Format', 'uint8');
    % Initialize all 3 ints to zero
    m.Data(1) = 0;
    m.Data(2) = 0;
    m.Data(3) = 0;
   
   while value==1
    value=app.RunButton.Value;
%     t=GetSecs;
    refreshdata(app.magstimUIFig)
    drawnow
    % do the magic
    if app.sport.BytesAvailable >= 24 %%%% min num of good magstim status response
     % use either fscanf (read as text)
     %         or fread  (read as binary)
     % search read data for magstim's "status" characters. Message begins with 'J'
     % and ends with 'ó'. So, find last occurrence of 'ó' character (=243), 
     % then find the last 'J' before that.
     % magstim power is the 3 characters after 'J'
     % call its output "magstimpower" and update GUI power field
%      app.sport.BytesAvailable
     
     msmsg = fscanf(app.sport,'%s',app.sport.BytesAvailable);
%      disp(msmsg)
     %endInd = find(msmsg==243, 1, 'last');
     begInd = find(msmsg(1:end-11)== 'J', 1, 'last');
%      disp(['characters: ' msmsg(begInd+1:begInd+3) ' ' ...
%          msmsg(begInd+4:begInd+6) ' ' ...
%          msmsg(begInd+7:begInd+9)])
     magstimpower = str2double(msmsg(begInd+1:begInd+3));
     bistimpower  = str2double(msmsg(begInd+4:begInd+6));
     isi  = str2double(msmsg(begInd+7:begInd+9));
     
     if ~isnan(magstimpower)
        app.magstim_power.Value = magstimpower;
        
        if app.MagstimOnlyChkBox.Value == true
            app.magstim_isi.Value = isi;
            app.MagStimLabel.Visible = 'on';
            app.ConditioningStimLabel.Visible = 'off';
            app.TestStimLabel.Visible = 'off';
            app.MagStimLabel.Text = 'MagStim:';
            app.magstim_power_2.Value = bistimpower;
            bistimpower = 0;
            isi = 0;
        else
           app.magstim_isi.Value = isi;
           %         frewind(fid)
           %         fprintf(fid, '%3d', app.magstim_power.Value);
           % change labels depending upon isi
           if isi == 0 % operating in Bistim mode
            app.MagStimLabel.Visible = 'on';
            app.ConditioningStimLabel.Visible = 'off';
            app.TestStimLabel.Visible = 'off';
            app.MagStimLabel.Text = 'BiStim:';
            app.magstim_power_2.Value = magstimpower;
            bistimpower = magstimpower;
           elseif bistimpower == 0 % operating in Magstim mode
            app.MagStimLabel.Visible = 'on';
            app.ConditioningStimLabel.Visible = 'off';
            app.TestStimLabel.Visible = 'off';
            app.MagStimLabel.Text = 'MagStim:';
            app.magstim_power_2.Value = bistimpower;
           else % operating in Bistim with conditioning & test stims
            app.MagStimLabel.Visible = 'off';
            app.ConditioningStimLabel.Visible = 'on';
            app.TestStimLabel.Visible = 'on';
            app.magstim_power_2.Value = bistimpower;
           end
 
       end
       % save values to memmap
       m.Data(1) = magstimpower;
       m.Data(2) = bistimpower;
       m.Data(3) = isi;

     end
    end
    
    % don't flood the event queue
%     WaitSecs('UntilTime',t+app.Delay.Value/1000);
        pause(app.Delay.Value/1000)
   end
   fclose(app.sport);
%    fclose(fid);
   app.RunButton.Text = 'Run';
  end
  
 end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   set(0,'ShowHiddenHandles','on')
   ch = get(0,'Children');
   keeper=0;
   for i=1:length(ch)
    if strcmpi(ch(i).Name, 'Magstim Power') ...
      && strcmpi(ch(i).Tag,'Magstim Power')
     keeper=i;
     %oldapp=app;
    end
   end
   if keeper>=1
    ML_W_switch('mlw')
    delete(app)
    figure(ch(keeper));
    return
   end
   app.magstimUIFig.Tag = 'Magstim Power';
   find_ports(app)
   return
        end

        % Value changed function: RunButton
        function runstop(app, event)
   % have to make fn "read_magstim" because can't have inf loop
   % in the immediate callback function, I guess. (hangs forever)
   if ~isempty(app.sport)
    read_magstim(app)
%     job = batch('read_magstim', 0, {app})
   else
    beep
    disp('Cannot run: no serial port detected.')
    app.RunButton.Value=0;
    return
   end
        end

        % Button pushed function: FindPortsButton
        function find_ports(app, event)
   try
    fclose(instrfind);
    delete(instrfind);
   catch
   end
   z=instrfind;
   if ~isempty(z)
    disp('find_ports: could not clear old ports');
    return
   end
   
   [numports,portname] = findUSBserial(0);
   if numports==0
    app.sport=[];
    app.ports_menu.Items = 'No serial ports';
    disp('No serial ports found. C''est la vie.')
    return
   end
   
   app.sport = cell(1,1); %numports);
   app.ports_menu.Items={};
   prefix=cell(numports,1);
   for i=1:numports
    dot=strfind(portname{i},'.');
    app.ports_menu.Items{i} = portname{i}(dot+1:end);
    prefix{i} = portname{i}(1:dot);
   end
   app.ports_menu.UserData = prefix;
   app.currportprefix = prefix{1};
   if numports==1
    change_port(app)
   end
        end

        % Button pushed function: ClearPortsButton
        function clear_ports(app, event)
   if ~isempty(app.sport)
    try
     fclose(app.sport);
     disp('Port closed')
    catch
    end
   else
    disp('clear_ports: No serial port found')
    return
   end
   %clear app.sport
   app.sport = [];
   app.ports_menu.Items={'Click "Find Ports"'};
        end

        % Close request function: magstimUIFig
        function magstimUIFigCloseRequest(app, event)
   clear_ports(app)
   delete(app)
        end

        % Value changed function: ports_menu
        function change_port(app, event)
   try
    fclose(app.sport);
   catch
   end
   a = app.currportprefix; %%this is wrong, but temp
   b = app.ports_menu.Value;
   newportname = [a b];
   app.sport = serial(newportname,'BaudRate',9600);
   app.sport.DataBits=8; app.sport.Parity='none';
   app.sport.StopBits=1; app.sport.FlowControl='none';
   app.sport.Terminator = '?';
   app.sport.RequestToSend = 'off'; % output HIGH (default at powerup)
   app.currportname = newportname;
        end

        % Value changed function: MagstimOnlyChkBox
        function MagstimOnlyChkBoxValueChanged(app, event)
            value = app.MagstimOnlyChkBox.Value;
            if value == 1
                % disable 
                app.magstim_power_2.Enable = false;
                app.LabelPercent2.Enable = false;
                app.magstim_isi.Enable = false;
                app.msLabel.Enable = false;
                app.ISILabel.Enable = false;
            else
                % enable 
                app.magstim_power_2.Enable = true;
                app.LabelPercent2.Enable = true;
                app.magstim_isi.Enable = true;
                app.msLabel.Enable = true;
                app.ISILabel.Enable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.magstimUIFig = uifigure('Visible', 'off');
            app.magstimUIFig.Name = 'Magstim Power';
            app.magstimUIFig.CloseRequestFcn = createCallbackFcn(app, @magstimUIFigCloseRequest, true);
            app.RunButton = uibutton(app.magstimUIFig, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @runstop, true);
            app.RunButton.Text = 'Run';
            app.FindPortsButton = uibutton(app.magstimUIFig, 'push');
            app.FindPortsButton.ButtonPushedFcn = createCallbackFcn(app, @find_ports, true);
            app.FindPortsButton.Text = 'Find Ports';
            app.ClearPortsButton = uibutton(app.magstimUIFig, 'push');
            app.ClearPortsButton.ButtonPushedFcn = createCallbackFcn(app, @clear_ports, true);
            app.ClearPortsButton.Text = 'Clear Ports';
            app.Delay = uieditfield(app.magstimUIFig, 'numeric');
            app.Delay.Limits = [10 5000];
            app.Delay.RoundFractionalValues = 'on';
            app.Delay.ValueDisplayFormat = '%.0f';
            app.Delay.Value = 10;
            app.magstim_power = uieditfield(app.magstimUIFig, 'numeric');
            app.magstim_power.ValueDisplayFormat = '%.0f';
            app.ports_menu = uidropdown(app.magstimUIFig);
            app.ports_menu.Items = {'nothing yet!'};
            app.ports_menu.ValueChangedFcn = createCallbackFcn(app, @change_port, true);
            app.ports_menu.Value = 'nothing yet!';
            app.CheckeveryLabel = uilabel(app.magstimUIFig);
            app.CheckeveryLabel.Text = 'Check every:';
            app.msecLabel = uilabel(app.magstimUIFig);
            app.msecLabel.Text = 'msec';
            app.LabelPercent1 = uilabel(app.magstimUIFig);
            app.LabelPercent1.Text = '%';
            app.LabelPercent2 = uilabel(app.magstimUIFig);
            app.LabelPercent2.Text = '%';
            app.magstim_power_2 = uieditfield(app.magstimUIFig, 'numeric');
            app.magstim_power_2.ValueDisplayFormat = '%.0f';
            app.ISILabel = uilabel(app.magstimUIFig);
            app.ISILabel.Text = 'ISI:';
            app.magstim_isi = uieditfield(app.magstimUIFig, 'numeric');
            app.magstim_isi.ValueDisplayFormat = '%.0f';
            app.msLabel = uilabel(app.magstimUIFig);
            app.msLabel.Text = 'ms';
            app.MagStimLabel = uilabel(app.magstimUIFig);
            app.MagStimLabel.Text = 'MagStim:';
            app.ConditioningStimLabel = uilabel(app.magstimUIFig);
            app.ConditioningStimLabel.Visible = 'off';
            app.ConditioningStimLabel.Text = 'Conditioning Stim:';
            app.TestStimLabel = uilabel(app.magstimUIFig);
            app.TestStimLabel.Visible = 'off';
            app.TestStimLabel.Text = 'Test Stim:';
            app.MagstimOnlyChkBox = uicheckbox(app.magstimUIFig);
            app.MagstimOnlyChkBox.ValueChangedFcn = createCallbackFcn(app, @MagstimOnlyChkBoxValueChanged, true);
            app.MagstimOnlyChkBox.Text = 'Single Stimulator Connected';
            % Show the figure after all components are created
            app.magstimUIFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = magspy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.magstimUIFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.magstimUIFig)
        end
    end
end