classdef recruitment_curve < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        StartButton  matlab.ui.control.StateButton
        UITable      matlab.ui.control.Table
    end


    properties (Access = public)
        rc_data_mmap % mem map for communication with display_emg app
        rc_data      % recruit curve data (matrix w/cols: use, magstim_val, mep_val
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % rc data memory map file
           rc_filename = 'rc_data.data';
           if ~exist(rc_filename, 'file')
             
             error('MATLAB:recruitment_curve:cannotOpenFile', ...
                      'Cannot open file "%s": %s. Make sure display_emg app is running first.', ...
                      rc_filename, msg);
           end
           app.rc_data_mmap = memmapfile(rc_filename, 'Writable', true, ...
               'Format', {'uint8', [1 1], 'new_data'; 
                          'uint8', [1 1], 'magstim_val'; 
                          'double', [1 1], 'mep_val'});
        end

        % Value changed function: StartButton
        function start_stop(app, event)
            if app.StartButton.Value
                app.StartButton.Text = 'Stop';
                % clear the flag telling us there is new mep data
                app.rc_data_mmap.Data(1).new_data = uint8(0);
                rc_get_meps(app)
            else
                app.StartButton.Text = 'Start';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Recruitment Curve')
            xlabel(app.UIAxes, 'MagStim Value')
            ylabel(app.UIAxes, 'MEP Vp-p (µV)')
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @start_stop, true);
            app.StartButton.Text = 'Start';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Use'; 'MagStim'; 'MEP'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false false];
        end
    end

    methods (Access = public)

        % Construct app
        function app = recruitment_curve

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end