classdef review_emg_rc < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		ReviewEMGRCUIFigure            matlab.ui.Figure
		TabGroup                       matlab.ui.container.TabGroup
		FilesTab                       matlab.ui.container.Tab
		VerifiedbyCheckBox             matlab.ui.control.CheckBox
		VerfiiedWhenEditField          matlab.ui.control.EditField
		AnalyzedWhenEditField          matlab.ui.control.EditField
		VerifiedbyEditField            matlab.ui.control.EditField
		AnalyzedbyEditField            matlab.ui.control.EditField
		AnalysisdonebyLabel            matlab.ui.control.Label
		CheckBoxAverage                matlab.ui.control.CheckBox
		CheckBoxSici                   matlab.ui.control.CheckBox
		CheckBoxRc                     matlab.ui.control.CheckBox
		MwaveDurationEditField         matlab.ui.control.NumericEditField
		MwaveDurationmsLabel           matlab.ui.control.Label
		MaucEditField                  matlab.ui.control.NumericEditField
		MAUCVmsLabel                   matlab.ui.control.Label
		DatapointsCSVEditField         matlab.ui.control.EditField
		DatapointsCSVEditFieldLabel    matlab.ui.control.Label
		MmaxEditField                  matlab.ui.control.NumericEditField
		MmaxVEditFieldLabel            matlab.ui.control.Label
		MuscleEditField                matlab.ui.control.EditField
		MuscleEditFieldLabel           matlab.ui.control.Label
		SessionEditField               matlab.ui.control.EditField
		SessionEditFieldLabel          matlab.ui.control.Label
		MMaxFileEditField              matlab.ui.control.EditField
		MMaxFileEditFieldLabel         matlab.ui.control.Label
		EMGDataTxtEditField            matlab.ui.control.EditField
		EMGDataTxtEditFieldLabel       matlab.ui.control.Label
		MmaxtoRCButton                 matlab.ui.control.Button
		MMaxSelectButton               matlab.ui.control.Button
		EmgDataSelectButton            matlab.ui.control.Button
		ParametersTab                  matlab.ui.container.Tab
		ParameterFileLabel             matlab.ui.control.Label
		FixedinParameterFilePanel      matlab.ui.container.Panel
		PostTriggerLabel               matlab.ui.control.Label
		PreTriggerLabel                matlab.ui.control.Label
		DisplaySaveEMGLabel            matlab.ui.control.Label
		ActivationGoalLabel            matlab.ui.control.Label
		EMGSamplingFreqLabel           matlab.ui.control.Label
		MEPThresholdEditField          matlab.ui.control.NumericEditField
		MEPThresholdEditFieldLabel     matlab.ui.control.Label
		PreEMGValueButtonGroup         matlab.ui.container.ButtonGroup
		MaxPeaktoPeakButton            matlab.ui.control.RadioButton
		MeanRectifiedValueButton       matlab.ui.control.RadioButton
		VLabel                         matlab.ui.control.Label
		EMGPeaktoPeakValueButtonGroup  matlab.ui.container.ButtonGroup
		SubtractPreEMGppButton         matlab.ui.control.RadioButton
		UseMEPAbsoluteValueButton      matlab.ui.control.RadioButton
		msLabel                        matlab.ui.control.Label
		preEmgMaxEditField             matlab.ui.control.NumericEditField
		PreEMGRangeLabel               matlab.ui.control.Label
		preEmgMinEditField             matlab.ui.control.NumericEditField
	end


    properties (Access = public)
        params          % parameters of the emg data (sampFreq, pre & postTriggerTime)

        emg_data_fig    % figure to display an emg epoch
        h_disp_emg_axes % axes to display emg data epoch
        h_emg_line      % handle to emg data line
        h_t_min_line    % handle to vertical line at lower time for finding MEP
        h_t_max_line    % handle to vertical line at upper time for finding MEP

        h_uitable       % table with rc data point info
        row_displayed   % the table row number being displayed
        emg_data        % from emg_dat.txt, col 1 = magstim, cols2-301 are emg data
        emg_data_num_vals_ignore % the number of columns of data to ignore in the emg data file
        % early versions had col1 = magstim value, then emg data
        % after 2019-06-01, col1 = use/don't use (0 or 1), col2 = magstim value, then emg data
        h_radio_mep     % radiobutton group to choose between peak-to-peak & area under the curve
        h_stim_setup_text % text showing what stimulator setup (magstim or bistim) was used
        h_pre_stim_emg_line % pre stim emg line
		h_pre_stim_emg_pos_std_line % pre stim emg standard deviation line above zero
		h_pre_stim_emg_neg_std_line % pre stim emg standard deviation line below zero
        h_cs_line		% conditioning Stim line (vertical - showing time before the test stim)
        h_emg_auc_patch % emg area under the curve patch
        h_edit_mep_begin % edit field with the value of h_t_min_line
        h_edit_mep_dur   % edit field with the value of duration between h_t_min_line & h_t_max_line
        h_edit_mep_end   % edit field with the value of h_t_max_line
		h_edit_mep_max_so % stimulator output for mep-max amplitude
        h_edit_epoch     % edit field showing the epoch/trial number

		h_num_std		% number of standard deviations of the prestim emg to use to compute MEP latency
		h_select_meps	% button to select mep rows
        h_autocompute_mep % button to have algorithm find MEP begin and end
		h_rc_plateau_checkbox % checkbox to indicate if recruitment curve plateaued or not
        h_compute_close_mep_begin % pushbutton to do minor adjustments to the mep begin line
        h_compute_close_mep_end % pushbutton to do minor adjustments to the mep end line
        h_save_computed_mep_info % pushbutton to save the info used to compute mep
        h_edit_mep_done_by % edit field containing initials of person doing MEP times analysis
        h_edit_mep_done_when % edit field with date of when MEP times were analyzed
        mep_times_changed_flag % true if mep times changed since they were read in from mep_computed_info.txt file
        mep_info		% mep begin, end, and epochs used to compute it
        h_show_analysis_meps % button to show/select in the table, the meps used for analysis
        h_using_data_txt % text showing what data (rc or sici) was used for mep analysis
        h_mep_analysis_comments % comments about the mep analysis
		h_chkbx_mep_verified_by % checkbox for verifying the mep beg & end times 
		h_edit_mep_verified_by % edit box with initials of who verified the analysis
		h_edit_mep_verified_when % edit box with date of verification

        h_label_isi		 % label for ISI
        h_edit_isi		 % edit field with ISI
        %         preEmgMinEditField  % parameters to recompute pre

        rc_fig          % recruitment curve figure
        rc_axes         % rc axes
        rc_fit_ui       % struct with fit info edit & text uicontrols
        rc_fit_info     % computed rc fit info
        rc_highlight_line % datapoint highlighted because of selection in datapoint table

        sici_fig        % sici / icf figure
        sici_axes       % axes for sici mep values
        sici_ui         % struct with uicontrols for sici
        sici_info       % struct with sici info

        avg_emg_fig     % averge emg data figure
        avg_emg_axes    % average axes
        avg_num_lines   % number of lines in average axes

        user_initials   % the initials of the person running the app

    end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app, varargin)
            tmp = upper(inputdlg('Enter your Initials'));
            app.user_initials = tmp{:};

            movegui(app.ReviewEMGRCUIFigure,"north")
            request_param_fname = true;
            if nargin > 1
                param_fname = varargin{1};
                if exist(param_fname, 'file')
                    param_fname = which(param_fname);
                    request_param_fname = false;
                end
            end
            if request_param_fname % request parameter file
                disp('Select parameter file')
                [fname, pname] = uigetfile('*.txt', 'Select parameter file');
                param_fname = fullfile(pname, fname);
            end

            app.ParameterFileLabel.Text = ['Parameter File: ' param_fname];
            get_emg_rc_parameters(app, param_fname)


		end

		% Button pushed function: MMaxSelectButton
		function MMaxSelectButtonPushed(app, event)
            ML_W_switch('ml');
            cur_dir = pwd;
            guess_mmax_dir = strrep(cur_dir, 'tms', 'm_max');
            try
                cd(guess_mmax_dir)
                cd ..
            catch ME
                disp(ME)
            end
            [fname, pname] = uigetfile({'*.csv', '*.xlsx'}, 'Pick M-max File');
            if ~isequal(fname,0) && ~isequal(pname,0)

                app.MMaxFileEditField.Value = fullfile(pname, fname);
                update_mmax_info(app)
                %                 [m_max, m_auc, m_dur] = parse_mmax_file(app.MMaxFileEditField.Value, ...
                %                     app.SessionEditField.Value, app.MuscleEditField.Value);
                %                 if isnan(m_max)
                %                     m_max =  1;
                %                 end
                %                 app.MmaxEditField.Value = m_max;
                %                 if isnan(m_auc)
                %                     m_auc =  1;
                %                 end
                %                 app.MaucEditField.Value = m_auc;
                %                 if isnan(m_dur)
                %                     m_dur =  1;
                %                 end
                %                 app.MwaveDurationEditField.Value = m_dur;
            end
            try
                cd(cur_dir)
            catch ME
                disp(ME)
            end
		end

		% Button pushed function: EmgDataSelectButton
		function EmgDataSelectButtonPushed(app, event)
            ML_W_switch('ml');
            [fname, pname] = uigetfile('*.txt', 'Pick EMG Data txt File');
            if ~isequal(fname,0) && ~isequal(pname,0)
                app.EMGDataTxtEditField.Value = fullfile(pname, fname);
                EMGDataTxtEditFieldValueChanged(app)
            end
		end

		% Value changed function: EMGDataTxtEditField
		function EMGDataTxtEditFieldValueChanged(app, event)
            value = app.EMGDataTxtEditField.Value;
            % update gui
            [session, muscle] = parse_fname_for_session_muscle(value);
            app.SessionEditField.Value = session;
            app.MuscleEditField.Value = muscle;
            update_mmax_info(app)
            % load emg data
            app.emg_data = load(value);
            
			% after 2022-07-15 files have 5 values to ignore (use, upper stim value, lower stim value, isi, effective SO)
            app.emg_data_num_vals_ignore = 5; 
			% files created after 2019-06-01 but before 2022-07-22 have 2 values
			% before the emg data (use, magstim/upper stim value)
            file_info = dir(value);
			if file_info.datenum > datenum(2019,06,01) && file_info.datenum < datenum(2022,07,15)
				app.emg_data_num_vals_ignore = 2;
			elseif file_info.datenum < datenum(2019,06,01)
                % files created before 2019-06-01 have 1 value before the
				% emg data (use - binary 0 or 1 to use or not use that data point)
                app.emg_data_num_vals_ignore = 1;
			end


            % update checkboxes for rc, sici, & avg
            tmp = regexpi(app.EMGDataTxtEditField.Value, '_((rc)|(sici))_', 'match');
            if ~isempty(tmp)
                switch tmp{1}
                    case '_rc_'
                        %                         app.ButtonRc.Value = 1;
                        app.CheckBoxRc.Value = 1;
                        app.CheckBoxSici.Value = 0;
                        app.CheckBoxAverage.Value = 0;
                    case '_sici_'
                        %                         app.ButtonSici.Value = 1;
                        app.CheckBoxRc.Value = 0;
                        app.CheckBoxSici.Value = 1;
                        app.CheckBoxAverage.Value = 0;
                end
            else
                % no checkbox
                app.CheckBoxRc.Value = 0;
                app.CheckBoxSici.Value = 0;
                app.CheckBoxAverage.Value = 0;
            end

            % init fig with emg data & table and fig with RC
            init_review_figs(app)

            % change the datapoints default filename
            % check in analysis folder for datapoints.csv first
            [pn, fn, ~] = fileparts(value);
            new_pn = strrep(pn, [filesep 'data' filesep], [filesep 'analysis' filesep]);
            new_fn = strrep(fn, 'emg_data', 'datapoints');
            analysis_dp_file = fullfile(new_pn, [new_fn '.csv']);
            if exist(analysis_dp_file, 'file')
                app.DatapointsCSVEditField.Value = analysis_dp_file;
            else
                app.DatapointsCSVEditField.Value = fullfile(pn, [new_fn '.csv']);
            end

            DatapointsCSVEditFieldValueChanged(app)

		end

		% Value changed function: SessionEditField
		function SessionEditFieldValueChanged(app, event)
            %             value = app.SessionEditField.Value;
            update_mmax_info(app)
		end

		% Value changed function: DatapointsCSVEditField
		function DatapointsCSVEditFieldValueChanged(app, event)
            value = app.DatapointsCSVEditField.Value;
            stimulator_setup = 'Unknown';
            % read in the rc datapoints table
            if exist(value, 'file')
                % 2022-07-16 - add provision to read in table with a header line with the
                % magstim or bistim label indicating what system was used
                % read first line, see if it contains 'magstim' or 'bistim'
                fid = fopen(value, 'rt');
                first_line = fgetl(fid);
                fclose(fid);
                if strcmpi(first_line, 'magstim') || strcmpi(first_line, 'bistim')
                    stimulator_setup = first_line;
                    num_header_lines = 1;
                else
                    % no stimulator as first line
                    num_header_lines = 0;
                    if app.CheckBoxSici.Value == 1
                        stimulator_setup = 'Bistim';
                    else
                        % ask the user stimulator setup
                        q_str = 'What stimulator setup was used?';
                        tlt = 'Stimulator Setup';
                        app.ReviewEMGRCUIFigure.WindowStyle = 'alwaysontop';
                        ans_butt = uiconfirm(app.ReviewEMGRCUIFigure, q_str, tlt, ...
                            'Options', {'Magstim', 'Bistim'}, ...
                            'DefaultOption', 2);
                        app.ReviewEMGRCUIFigure.WindowStyle = 'normal';
                        % 					disp(ans_butt)
                        stimulator_setup = lower(ans_butt);
                    end
                end
                % table read in
                tbl = readtable(value, 'NumHeaderLines',num_header_lines);
            else
                % compute the table
                tbl = table();
            end
            app.h_stim_setup_text.String = [upper(stimulator_setup(1)), lower(stimulator_setup(2:end))];

            % update 2022-07-22: get mep times from mep_computed_info.txt file
			app.mep_info = struct();
            app.mep_info = get_computed_info_mep_times(value);
            % did not find the info, use default values
            if ~isfield(app.mep_info, 'mep_beg_t')
                app.mep_info.mep_beg_t = 10;
            end
            if ~isfield(app.mep_info, 'mep_end_t')
                app.mep_info.mep_end_t = 90;
            end
            if ~isfield(app.mep_info, 'epochs_used_for_latency')
                app.mep_info.epochs_used_for_latency = [];
            end
            if ~isfield(app.mep_info, 'analyzed_by')
                app.mep_info.analyzed_by = '';
            end
            if ~isfield(app.mep_info, 'analyzed_when')
                app.mep_info.analyzed_when = '';
            end
            if ~isfield(app.mep_info, 'verified_by')
                app.mep_info.verified_by = '';
            end
            if ~isfield(app.mep_info, 'verified_when')
                app.mep_info.verified_when = '';
            end
            if ~isfield(app.mep_info, 'using_rc_or_sici_data')
                app.mep_info.using_rc_or_sici_data = '???';
            end
            if ~isfield(app.mep_info, 'comments')
                app.mep_info.comments = '';
            end
			if ~isfield(app.mep_info, 'num_std_dev')
                app.mep_info.num_std_dev = 3;
			end
			if ~isfield(app.mep_info, 'mep_max_so')
                app.mep_info.mep_max_so = nan;
			end
            if ~isfield(app.mep_info, 'rc_plateau')
                app.mep_info.rc_plateau = 0;
            end
            app.mep_times_changed_flag = false;

            % get mep begin & end times from a fit info file
            % 			[mep_beg_t, mep_end_t] = get_fit_info_mep_times(value);
            % update values from info read in
            app.h_t_min_line.XData = [app.mep_info.mep_beg_t, app.mep_info.mep_beg_t];
            app.h_edit_mep_begin.String = num2str(round(app.mep_info.mep_beg_t, 1));
			
			app.h_t_max_line.XData = [app.mep_info.mep_end_t, app.mep_info.mep_end_t];
            app.h_edit_mep_end.String = num2str(round(app.mep_info.mep_end_t, 1));
            
            app.h_edit_mep_dur.String = num2str(round(app.mep_info.mep_end_t - app.mep_info.mep_beg_t, 1));
            app.h_edit_mep_done_by.String = app.mep_info.analyzed_by;
            app.h_edit_mep_done_when.String = app.mep_info.analyzed_when;
            app.h_using_data_txt.String = ['Using ' app.mep_info.using_rc_or_sici_data ' data'];
            app.h_mep_analysis_comments.String = app.mep_info.comments; 
			app.h_num_std.String = num2str(app.mep_info.num_std_dev);
			if isnan(app.mep_info.mep_max_so)
				app.h_edit_mep_max_so.String = '?';
			else
				app.h_edit_mep_max_so.String = num2str(app.mep_info.mep_max_so);
			end
			app.h_rc_plateau_checkbox.Value = app.mep_info.rc_plateau;
			if isfield(app.mep_info, 'e_stim_m_max_uV')
				if isempty(app.mep_info.e_stim_m_max_uV)
					disp('E stim m-max value read in is empty')
				end
				if app.mep_info.e_stim_m_max_uV == 1
					disp('E stim m-max value read in = 1')
				end
			end
				
			% add verified by and when - FIXME
			if ~isempty(app.mep_info.verified_by)
% 				keyboard
				app.h_chkbx_mep_verified_by.Value = 1;
				app.h_edit_mep_verified_by.String = app.mep_info.verified_by;
				app.h_edit_mep_verified_when.String = app.mep_info.verified_when;
			else
				app.h_chkbx_mep_verified_by.Value = 0;
				app.h_edit_mep_verified_by.String = '';
				app.h_edit_mep_verified_when.String = '';
			end
            % initialize the datapoint uitable
            init_datapoint_table(app, tbl)
            % put the datapoints in the rc fig
            load_stim_emg_data([],[], app)

            % get already computed rc or sici info
            get_previous_rc_or_sici_info(app)
		end

		% Button pushed function: MmaxtoRCButton
		function MmaxtoRCButtonPushed(app, event)
            switch app.MmaxtoRCButton.Text
                case {'M-max to RC', 'M-max to SICI'}
                    app.rc_fit_ui.edNormFactor.String = num2str(app.MmaxEditField.Value);
                case {'M AUC to RC', 'M AUC to SICI'}
                    app.rc_fit_ui.edNormFactor.String = num2str(app.MaucEditField.Value);
            end

            rc_change_norm_factor([], [], app)
            % get already computed rc or sici info
			get_previous_rc_or_sici_info(app)
            
		end

		% Close request function: ReviewEMGRCUIFigure
		function ReviewEMGRCUIFigureCloseRequest(app, event)
            % close figures before deleting the app
            if isgraphics(app.rc_fig)
                delete(app.rc_fig)
            end
            if isgraphics(app.sici_fig)
                delete(app.sici_fig)
            end
            if isgraphics(app.emg_data_fig)
                delete(app.emg_data_fig)
            end
            delete(app)

		end

		% Value changed function: CheckBoxAverage
		function CheckBoxAverageValueChanged(app, event)
            if app.CheckBoxAverage.Value
                display_average_emg(app, 'start')
            else
                display_average_emg(app, 'stop')
            end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.ReviewEMGRCUIFigure = uifigure('Visible', 'off');
			app.ReviewEMGRCUIFigure.Name = 'Review EMG RC';
			app.ReviewEMGRCUIFigure.CloseRequestFcn = createCallbackFcn(app, @ReviewEMGRCUIFigureCloseRequest, true);
			app.TabGroup = uitabgroup(app.ReviewEMGRCUIFigure);
			app.FilesTab = uitab(app.TabGroup);
			app.FilesTab.Title = 'Files';
			app.EmgDataSelectButton = uibutton(app.FilesTab, 'push');
			app.EmgDataSelectButton.ButtonPushedFcn = createCallbackFcn(app, @EmgDataSelectButtonPushed, true);
			app.EmgDataSelectButton.Text = 'Select';
			app.MMaxSelectButton = uibutton(app.FilesTab, 'push');
			app.MMaxSelectButton.ButtonPushedFcn = createCallbackFcn(app, @MMaxSelectButtonPushed, true);
			app.MMaxSelectButton.Text = 'Select';
			app.MmaxtoRCButton = uibutton(app.FilesTab, 'push');
			app.MmaxtoRCButton.ButtonPushedFcn = createCallbackFcn(app, @MmaxtoRCButtonPushed, true);
			app.MmaxtoRCButton.Text = 'M-max to RC';
			app.EMGDataTxtEditFieldLabel = uilabel(app.FilesTab);
			app.EMGDataTxtEditFieldLabel.Text = 'EMG Data Txt ';
			app.EMGDataTxtEditField = uieditfield(app.FilesTab, 'text');
			app.EMGDataTxtEditField.ValueChangedFcn = createCallbackFcn(app, @EMGDataTxtEditFieldValueChanged, true);
			app.MMaxFileEditFieldLabel = uilabel(app.FilesTab);
			app.MMaxFileEditFieldLabel.Text = 'M-Max File';
			app.MMaxFileEditField = uieditfield(app.FilesTab, 'text');
			app.SessionEditFieldLabel = uilabel(app.FilesTab);
			app.SessionEditFieldLabel.Text = 'Session';
			app.SessionEditField = uieditfield(app.FilesTab, 'text');
			app.SessionEditField.ValueChangedFcn = createCallbackFcn(app, @SessionEditFieldValueChanged, true);
			app.MuscleEditFieldLabel = uilabel(app.FilesTab);
			app.MuscleEditFieldLabel.Text = 'Muscle';
			app.MuscleEditField = uieditfield(app.FilesTab, 'text');
			app.MmaxVEditFieldLabel = uilabel(app.FilesTab);
			app.MmaxVEditFieldLabel.Text = 'M-max (µV)';
			app.MmaxEditField = uieditfield(app.FilesTab, 'numeric');
			app.MmaxEditField.ValueDisplayFormat = '%4.0f';
			app.MmaxEditField.Value = 1;
			app.DatapointsCSVEditFieldLabel = uilabel(app.FilesTab);
			app.DatapointsCSVEditFieldLabel.Text = 'Datapoints CSV';
			app.DatapointsCSVEditField = uieditfield(app.FilesTab, 'text');
			app.DatapointsCSVEditField.ValueChangedFcn = createCallbackFcn(app, @DatapointsCSVEditFieldValueChanged, true);
			app.MAUCVmsLabel = uilabel(app.FilesTab);
			app.MAUCVmsLabel.Text = 'M-AUC (µV*ms)';
			app.MaucEditField = uieditfield(app.FilesTab, 'numeric');
			app.MaucEditField.ValueDisplayFormat = '%4.0f';
			app.MaucEditField.Value = 1;
			app.MwaveDurationmsLabel = uilabel(app.FilesTab);
			app.MwaveDurationmsLabel.Text = 'M-wave Dur (ms)';
			app.MwaveDurationEditField = uieditfield(app.FilesTab, 'numeric');
			app.MwaveDurationEditField.ValueDisplayFormat = '%2.1f';
			app.MwaveDurationEditField.Value = 1;
			app.CheckBoxRc = uicheckbox(app.FilesTab);
			app.CheckBoxRc.Text = 'Recruit Curve';
			app.CheckBoxSici = uicheckbox(app.FilesTab);
			app.CheckBoxSici.Text = 'SICI/ICF';
			app.CheckBoxAverage = uicheckbox(app.FilesTab);
			app.CheckBoxAverage.ValueChangedFcn = createCallbackFcn(app, @CheckBoxAverageValueChanged, true);
			app.CheckBoxAverage.Text = 'Average';
			app.AnalysisdonebyLabel = uilabel(app.FilesTab);
			app.AnalysisdonebyLabel.Text = 'Analyzed by';
			app.AnalyzedbyEditField = uieditfield(app.FilesTab, 'text');
			app.AnalyzedbyEditField.Value = '???';
			app.VerifiedbyEditField = uieditfield(app.FilesTab, 'text');
			app.VerifiedbyEditField.Value = '???';
			app.AnalyzedWhenEditField = uieditfield(app.FilesTab, 'text');
			app.AnalyzedWhenEditField.Value = '2022-00-00';
			app.VerfiiedWhenEditField = uieditfield(app.FilesTab, 'text');
			app.VerfiiedWhenEditField.Value = '2022-00-00';
			app.VerifiedbyCheckBox = uicheckbox(app.FilesTab);
			app.VerifiedbyCheckBox.Text = 'Verified by';
			app.ParametersTab = uitab(app.TabGroup);
			app.ParametersTab.Title = 'Parameters';
			app.preEmgMinEditField = uieditfield(app.ParametersTab, 'numeric');
			app.preEmgMinEditField.Value = -101;
			app.PreEMGRangeLabel = uilabel(app.ParametersTab);
			app.PreEMGRangeLabel.Text = 'Pre EMG Range';
			app.preEmgMaxEditField = uieditfield(app.ParametersTab, 'numeric');
			app.preEmgMaxEditField.Value = -1;
			app.msLabel = uilabel(app.ParametersTab);
			app.msLabel.Text = 'ms';
			app.EMGPeaktoPeakValueButtonGroup = uibuttongroup(app.ParametersTab);
			app.EMGPeaktoPeakValueButtonGroup.Title = 'EMG Peak-to-Peak Value';
			app.UseMEPAbsoluteValueButton = uiradiobutton(app.EMGPeaktoPeakValueButtonGroup);
			app.UseMEPAbsoluteValueButton.Text = 'Use MEP Absolute Value';
			app.UseMEPAbsoluteValueButton.Value = true;
			app.SubtractPreEMGppButton = uiradiobutton(app.EMGPeaktoPeakValueButtonGroup);
			app.SubtractPreEMGppButton.Text = 'Subtract Pre EMG p-p';
			app.VLabel = uilabel(app.ParametersTab);
			app.VLabel.Text = 'µV';
			app.PreEMGValueButtonGroup = uibuttongroup(app.ParametersTab);
			app.PreEMGValueButtonGroup.Title = 'Pre EMG Value';
			app.MeanRectifiedValueButton = uiradiobutton(app.PreEMGValueButtonGroup);
			app.MeanRectifiedValueButton.Text = 'Mean Rectified Value';
			app.MeanRectifiedValueButton.Value = true;
			app.MaxPeaktoPeakButton = uiradiobutton(app.PreEMGValueButtonGroup);
			app.MaxPeaktoPeakButton.Text = 'Max Peak-to-Peak';
			app.MEPThresholdEditFieldLabel = uilabel(app.ParametersTab);
			app.MEPThresholdEditFieldLabel.Text = 'MEP Threshold';
			app.MEPThresholdEditField = uieditfield(app.ParametersTab, 'numeric');
			app.MEPThresholdEditField.Value = 200;
			app.FixedinParameterFilePanel = uipanel(app.ParametersTab);
			app.FixedinParameterFilePanel.Title = 'Fixed in Parameter File';
			app.EMGSamplingFreqLabel = uilabel(app.FixedinParameterFilePanel);
			app.EMGSamplingFreqLabel.Text = 'EMG Sampling Freq: xxxx Hz';
			app.ActivationGoalLabel = uilabel(app.FixedinParameterFilePanel);
			app.ActivationGoalLabel.Text = 'Activation Goal: xx% mvc';
			app.DisplaySaveEMGLabel = uilabel(app.FixedinParameterFilePanel);
			app.DisplaySaveEMGLabel.Text = 'Display & Save EMG:';
			app.PreTriggerLabel = uilabel(app.FixedinParameterFilePanel);
			app.PreTriggerLabel.Text = 'Pre Trigger: xxx ms';
			app.PostTriggerLabel = uilabel(app.FixedinParameterFilePanel);
			app.PostTriggerLabel.Text = 'Post Trigger: xxx ms';
			app.ParameterFileLabel = uilabel(app.ParametersTab);
			app.ParameterFileLabel.Text = 'Parameter File:';
			% Show the figure after all components are created
			app.ReviewEMGRCUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = review_emg_rc(varargin)

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.ReviewEMGRCUIFigure)

				% Execute the startup function
				runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
			else

				% Focus the running singleton app
				figure(runningApp.ReviewEMGRCUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.ReviewEMGRCUIFigure)
		end
	end
end