classdef two_muscle_bar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EMG_1_train_goal               matlab.ui.control.NumericEditField
        EMG_1_train_goal_label         matlab.ui.control.Label
        app_to_base_button             matlab.ui.control.Button
        StartButton                    matlab.ui.control.StateButton
        MarkMVCCheckBox                matlab.ui.control.CheckBox
        YminEditField_2                matlab.ui.control.NumericEditField
        YminEditFieldLabel_2           matlab.ui.control.Label
        YminEditField_1                matlab.ui.control.NumericEditField
        YminEditFieldLabel             matlab.ui.control.Label
        EMG_2_rest_thresholdEditField  matlab.ui.control.NumericEditField
        RestThresholdLabel_2           matlab.ui.control.Label
        EMG_1_rest_thresholdEditField  matlab.ui.control.NumericEditField
        RestThresholdLabel             matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        YmaxEditField_2                matlab.ui.control.NumericEditField
        YmaxEditField_2Label           matlab.ui.control.Label
        YmaxEditField_1                matlab.ui.control.NumericEditField
        YmaxEditFieldLabel             matlab.ui.control.Label
        ShowGoalCheckBox               matlab.ui.control.CheckBox
        msgText                        matlab.ui.control.Label
        EMGDropDown_2                  matlab.ui.control.DropDown
        EMGDropDown_2Label             matlab.ui.control.Label
        EMGDropDown_1                  matlab.ui.control.DropDown
        EMGDropDownLabel               matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_1                       matlab.ui.control.UIAxes
    end


    properties (Access = private)

    end

    properties (Access = public)
        tcp_port % Description
        param_fname % parameters file name
        params % parameters for tcpip connection to brainvision
        hLine_1 % handle to emg data 1 vertical line
        hLine_2 % handle to emg data 2 vertical line
        hStarMark % asterix text marker when there is a stim pulse
        monitorFlg % true = monitoring emg and showing goal
        chanInfo % emg channel info
        emgBarDataVec % vector to hold the incoming emg data for the bar display
        emgTriggerDataMat % Matrix for emg data surrounding the trigger
        hpFilt % parameters for high pass filter
        peakTracker % tracking the peak
        peakValVec % vector of values to compute peak
        peakVal % the compute peak value
        monitorEMGval % value of the emg monitor at a stim pulse
        % 		emg_1_rest_threshold % emg_1 resting threshold
        % 		emg_2_rest_threshold % emg_2 resting threshold
        goalVal % the emg goal value
        goalMin % the emg goal min value
        goalMax % the emg goal max value
        hPeakLine % handle to horizontal peak line
        hGoalLines % handles to goal lines
        emg_1_inGreenFlag
        emg_2_inGreenFlag   % flag = true when monitor goal is in the green
        inGreenTstart % hold the start time for when goal became green

        magstim_mmap % memory map for getting magstim value from magspy
        emg_data_mmap % memory map to send emg data
        data_channels_mmap   % memory map with list of all muscle channels - used by emg_rc.mlapp

    end

    methods (Access = private)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
			app.emg_1_inGreenFlag = false;
			app.emg_2_inGreenFlag = false;
			if nargin > 1
				app.param_fname = varargin{1};
			else % request parameter file
				disp('Select parameter file')
				[fname, pname] = uigetfile('*.txt', 'Select parameter file');
				app.param_fname = fullfile(pname, fname);
			end

			% screen size
			sz = get( 0, 'ScreenSize');

			app.UIFigure.AutoResizeChildren = 'off';
			app.UIFigure.AutoResizeChildren = 'on';

			app.UIFigure.Position = [1 1 500 sz(4)];

			app.UIAxes_1.Position = [30 175 155 900];
			app.UIAxes_2.Position = [250 175 155 900];

			%             app.UIAxes_1.Position = [0.1 0.1 0.8 0.85];

			app.UIAxes_1.XLim = [0.25 1.75];
			app.UIAxes_1.YLim = [-10 300];
			app.UIAxes_1.YTick = [-10:10:40, 50:50:1000];
			app.UIAxes_1.XTick = [];

			app.UIAxes_2.XLim = [0.25 1.75];
			app.UIAxes_2.YLim = [-10 300];
			app.UIAxes_2.YTick = [-10:10:40, 50:50:1000];
			app.UIAxes_2.XTick = [];

			% if on actichamp plus eeg computer, adjust the window position
			[~, computer_name] = system('hostname');
			if contains(computer_name, 'DESKTOP-MBG1914')
				app.UIFigure.Position = [3 50 400 1000];
				app.UIAxes_1.Units = 'pixels';
				app.UIAxes_1.Position = [20 175 155 810];
                app.UIAxes_2.Units = 'pixels';
				app.UIAxes_2.Position = [215 175 155 810];
				assignin('base', 'app', app)
			end

			app.UIAxes_1.Interactions = [];
			app.hLine_1 = line(app.UIAxes_1, [1 1], [-20 1], 'LineWidth', 50);
			app.hStarMark = text(app.UIAxes_1, -0.3, 0, '*');
			app.UIAxes_2.Interactions = [];
			app.hLine_2 = line(app.UIAxes_2, [1 1], [-20 10], 'LineWidth', 50);
			app.hStarMark = text(app.UIAxes_2, -0.3, 0, '*');
			app.monitorFlg = false;
			app.chanInfo = struct('resolution',[], 'names',{});

			drawGoal_2mb(app)

% 			app.peakTracker = false;
% 			app.peakValVec = zeros(1,100);
% 			app.peakVal = 0;
% 			app.hPeakLine = line(app.UIAxes_1, [0.5 1.5], [app.peakVal, app.peakVal], ...
% 				'LineWidth', 20, ...
% 				'Color', [209 36 36]/255, 'Visible', 'off');
% 			app.MarkMVCCheckBox.Value = true;

% 			% magstim
% 			% open file for memmap and communicating
% 			filename = 'magstim_val.data';
% 			if ~exist(filename, 'file')
% 				[f, msg] = fopen(filename, 'wb');
% 				if f ~= -1
% 					fwrite(f, zeros(1,3), 'uint8');
% 					fclose(f);
% 				else
% 					error('MATLAB:demo:send:cannotOpenFile', ...
% 						'Cannot open file "%s": %s.', filename, msg);
% 				end
% 			end
% 			% Memory map the file.
% 			app.magstim_mmap = memmapfile(filename, 'Format', 'uint8');

			% BrainVision & recording parameters
			get_emg_act_monitor_parameters(app, app.param_fname)

% 			% memmap - file to send emg data to emg display app
% 			setup_memmap(app, 'emg_data')
% 
% 			% memmap to send all the muscle channels to emg_rc
% 			setup_memmap(app, 'data_channels');

        end

        % Value changed function: StartButton
        function get_settings(app, event)
			emg_data_start_stop_2mb(app)

        end

        % Value changed function: YmaxEditField_1
        function yMax_1_Callback(app, event)
			value = app.YmaxEditField_1.Value;
			app.UIAxes_1.YLim(2) = value;
        end

        % Value changed function: ShowGoalCheckBox
        function showGoal(app, event)
			app.monitorFlg = app.ShowGoalCheckBox.Value;
			if app.monitorFlg
				drawGoal(app);
				% 				yMax = max(ceil(app.params.goalPct * app.peakVal * 2), 1);
				app.peakTracker = false;
			else
				removeGoal(app);
				yMax = max(ceil(1.2 * app.peakVal), 1);
				app.peakTracker = false; % no peak tracking for now

				set(app.YmaxEditField_1, 'Value', yMax);
				set(app.UIAxes_1, 'YLim', [0 yMax]);
				app.peakTracker = false;
			end
        end

        % Value changed function: EMG_1_rest_thresholdEditField
        function emg_1_thresh_callback(app, event)
% 			app.emg_1_rest_threshold = app.EMG_1_rest_thresholdEditField.Value;
			drawGoal_2mb(app)
			app.emg_1_inGreenFlag = false;
        end

        % Value changed function: MarkMVCCheckBox
        function markMvc(app, event)
			% 			app.peakTracker = app.MarkMVCCheckBox.Value;
			% 			if app.peakTracker
			% 				set(app.ResetButton, 'Enable', 'on');
			% 			else
			% 				set(app.ResetButton, 'Enable', 'off');
			% 			end
        end

        % Value changed function: EMGDropDown_1, EMGDropDown_2
        function EMGDropDown_ValueChanged(app, event)
			% which dropdown
			dd_num_cell  = regexp(event.Source.Tag, '\d+', 'match');
			dd_num_str = dd_num_cell{:};

			emg_dd_str = ['EMGDropDown_', dd_num_str];
			prev_ind = find(strcmp(app.(emg_dd_str).Items, event.PreviousValue));
			cur_ind = find(strcmp(app.(emg_dd_str).Items, event.Value));
			% set live_display flag data_channels mmap
			app.data_channels_mmap.Data(prev_ind).live_display = uint8(0);
			app.data_channels_mmap.Data(cur_ind).live_display = uint8(1);
        end

        % Value changed function: YmaxEditField_2
        function Ymax_2_Callback(app, event)
			value = app.YmaxEditField_2.Value;
			app.UIAxes_2.YLim(2) = value;
        end

        % Value changed function: YminEditField_1
        function YminEditField_1ValueChanged(app, event)
			value = app.YminEditField_1.Value;
			if value < app.UIAxes_1.YLim(2)
				app.UIAxes_1.YLim(1) = value;
			else
				app.UIAxes_1.YLim(1) = 0;
				app.YminEditField_1.Value = 0;
			end
        end

        % Value changed function: YminEditField_2
        function YminEditField_2ValueChanged(app, event)
			value = app.YminEditField_2.Value;
			if value < app.UIAxes_2.YLim(2)
				app.UIAxes_2.YLim(1) = value;
			else
				app.UIAxes_2.YLim(1) = 0;
				app.YminEditField_2.Value = 0;
			end
        end

        % Value changed function: EMG_2_rest_thresholdEditField
        function EMG_2_rest_thresholdEditFieldValueChanged(app, event)
% 			app.emg_2_rest_threshold = app.EMG_2_rest_thresholdEditField.Value;
			drawGoal_2mb(app)
			app.emg_2_inGreenFlag = false;
        end

        % Button pushed function: app_to_base_button
        function app_to_base_buttonButtonPushed(app, event)
            assignin('base', 'app', app)
        end

        % Value changed function: EMG_1_train_goal
        function EMG_1_train_goalValueChanged(app, event)
%             value = app.EMG_1_train_goal.Value;
            drawGoal_2mb(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            app.UIAxes_1.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.YTick = [];
            app.EMGDropDownLabel = uilabel(app.UIFigure);
            app.EMGDropDownLabel.Text = 'EMG:';
            app.EMGDropDown_1 = uidropdown(app.UIFigure);
            app.EMGDropDown_1.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.EMGDropDown_1.ValueChangedFcn = createCallbackFcn(app, @EMGDropDown_ValueChanged, true);
            app.EMGDropDown_1.Tag = 'dropdown_1';
            app.EMGDropDown_1.Value = '1';
            app.EMGDropDown_2Label = uilabel(app.UIFigure);
            app.EMGDropDown_2Label.Text = 'EMG:';
            app.EMGDropDown_2 = uidropdown(app.UIFigure);
            app.EMGDropDown_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.EMGDropDown_2.ValueChangedFcn = createCallbackFcn(app, @EMGDropDown_ValueChanged, true);
            app.EMGDropDown_2.Tag = 'dropdown_2';
            app.EMGDropDown_2.Value = '2';
            app.msgText = uilabel(app.UIFigure);
            app.msgText.Text = 'no message';
            app.ShowGoalCheckBox = uicheckbox(app.UIFigure);
            app.ShowGoalCheckBox.ValueChangedFcn = createCallbackFcn(app, @showGoal, true);
            app.ShowGoalCheckBox.Text = 'Show Goal';
            app.YmaxEditFieldLabel = uilabel(app.UIFigure);
            app.YmaxEditFieldLabel.Text = 'Ymax =';
            app.YmaxEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.YmaxEditField_1.ValueChangedFcn = createCallbackFcn(app, @yMax_1_Callback, true);
            app.YmaxEditField_1.Value = 300;
            app.YmaxEditField_2Label = uilabel(app.UIFigure);
            app.YmaxEditField_2Label.Text = 'Ymax =';
            app.YmaxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YmaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @Ymax_2_Callback, true);
            app.YmaxEditField_2.Value = 300;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.RestThresholdLabel = uilabel(app.UIFigure);
            app.RestThresholdLabel.Text = 'RestThreshold =';
            app.EMG_1_rest_thresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.EMG_1_rest_thresholdEditField.ValueChangedFcn = createCallbackFcn(app, @emg_1_thresh_callback, true);
            app.EMG_1_rest_thresholdEditField.Value = 5;
            app.RestThresholdLabel_2 = uilabel(app.UIFigure);
            app.RestThresholdLabel_2.Text = 'RestThreshold =';
            app.EMG_2_rest_thresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.EMG_2_rest_thresholdEditField.ValueChangedFcn = createCallbackFcn(app, @EMG_2_rest_thresholdEditFieldValueChanged, true);
            app.EMG_2_rest_thresholdEditField.Value = 5;
            app.YminEditFieldLabel = uilabel(app.UIFigure);
            app.YminEditFieldLabel.Text = 'Ymin =';
            app.YminEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.YminEditField_1.ValueChangedFcn = createCallbackFcn(app, @YminEditField_1ValueChanged, true);
            app.YminEditField_1.Value = -10;
            app.YminEditFieldLabel_2 = uilabel(app.UIFigure);
            app.YminEditFieldLabel_2.Text = 'Ymin =';
            app.YminEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YminEditField_2.ValueChangedFcn = createCallbackFcn(app, @YminEditField_2ValueChanged, true);
            app.YminEditField_2.Value = -10;
            app.MarkMVCCheckBox = uicheckbox(app.UIFigure);
            app.MarkMVCCheckBox.ValueChangedFcn = createCallbackFcn(app, @markMvc, true);
            app.MarkMVCCheckBox.Visible = 'off';
            app.MarkMVCCheckBox.Text = 'Mark MVC';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @get_settings, true);
            app.StartButton.Text = 'Start';
            app.app_to_base_button = uibutton(app.UIFigure, 'push');
            app.app_to_base_button.ButtonPushedFcn = createCallbackFcn(app, @app_to_base_buttonButtonPushed, true);
            app.app_to_base_button.Text = 'app to base';
            app.EMG_1_train_goal_label = uilabel(app.UIFigure);
            app.EMG_1_train_goal_label.Text = 'Training Goal =';
            app.EMG_1_train_goal = uieditfield(app.UIFigure, 'numeric');
            app.EMG_1_train_goal.ValueChangedFcn = createCallbackFcn(app, @EMG_1_train_goalValueChanged, true);
            app.EMG_1_train_goal.Value = 40;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = two_muscle_bar(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end